classdef Rolle_item < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        TextArea_4   matlab.ui.control.TextArea
        Label_7      matlab.ui.control.Label
        TextArea_2   matlab.ui.control.TextArea
        Button_4     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Image        matlab.ui.control.Image
        Label_4      matlab.ui.control.Label
        TextArea     matlab.ui.control.TextArea
        bTextArea    matlab.ui.control.TextArea
        abLabel      matlab.ui.control.Label
        abSpinner    matlab.ui.control.Spinner
        abLabel_2    matlab.ui.control.Label
        Slider       matlab.ui.control.Slider
        Label_5      matlab.ui.control.Label
        EditField_2  matlab.ui.control.NumericEditField
        Label_3      matlab.ui.control.Label
        EditField    matlab.ui.control.NumericEditField
        Label_2      matlab.ui.control.Label
        fafbSlider   matlab.ui.control.Slider
        fafbLabel    matlab.ui.control.Label
        Button       matlab.ui.control.StateButton
        UIAxes       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)        
       function results = line_tangent(app,x0,m,a)
            syms x;
            y0=eval(m(x0));
            k=diff(m,x);
            k=eval(k(x0));
            b=y0-k*x0;
            results=k*a+b;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
    cla(app.UIAxes);
        % 获取输入的函数字符串
        syms x;
        syms m(x);
        func_st= app.TextArea.Value;

        func_str=string(func_st);
        % 创建函数句柄
        f = eval(func_str);
        m(x)=f;
        a=app.EditField.Value;
        b=app.EditField_2.Value;
        
        f1=app.fafbSlider.Value;
        yline(app.UIAxes,app.fafbSlider.Value);
        assume(x>a&x<b);
        sol_y=solve(f1==m,x);
        scatter(app.UIAxes, sol_y, m(sol_y), 'b', 'filled');
        app.bTextArea.Value=string(sol_y);
        % 计算函数在区间端点处的值
        fa = eval(m(a));
        fb = eval(m(b));
        fsol_y=eval(m(sol_y));
        
        % 判断区间端点处是否满足罗尔定理条件
        % 计算函数的导数
        syms x ;
        df = diff(f,x);
        
        % 找到满足罗尔定理条件的点
        sol = solve(df == 0, x);        
        % 将符号解转换为 double
        sol_double = double(sol(sol >= a & sol <= sol_y(app.abSpinner.Value,1)));
        
        hold(app.UIAxes,'on');
        % 如果存在满足条件的点，则绘制函数图像和点
        if ~isempty(sol_double)
            % 绘制函数图像
            x_vals = a:0.1:b;
            y_vals =eval(m(x_vals));
            plot(app.UIAxes, x_vals, y_vals);
            hold(app.UIAxes, 'on');
            
            % 绘制满足罗尔定理条件的点
            scatter(app.UIAxes, sol_double, m(sol_double), 'r', 'filled');
      
            
            % 添加标签和标题
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'f(x)');
            title(app.UIAxes, 'Visualization of Rolle''s Theorem');
            
            % 显示图例
            legend(app.UIAxes, {'Function', 'Point satisfying Rolle''s Theorem'}, 'Location', 'best');
            
            hold(app.UIAxes, 'off');
        else
            msgbox('No point satisfying Rolle''s theorem condition found in the interval.', 'Rolle''s Theorem', 'modal');
        end

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
                  syms x;
        syms m(x);
        func_st= app.TextArea.Value;

        func_str=string(func_st);
        % 创建函数句柄
        f = eval(func_str);
        m(x)=f;
            value = app.Slider.Value;
            syms c; 
            x1=app.Slider.Value;
g=line_tangent(app,x1,m,c);
c=x1-1:0.01:x1+1;
            yc=eval(g);
                   cla(app.UIAxes);
                   scatter(app.UIAxes, x1, m(x1), 'g', 'filled');
                 
                  plot(app.UIAxes, c, yc,'r');
     
            Button_3Pushed(app, event)

        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app.UIFigure);
            run C:\Users\谢雨良\Desktop\Start_page.mlapp
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            syms x;
        syms m(x);
        func_st= app.TextArea.Value;

        func_str=string(func_st);
        % 创建函数句柄
        f = eval(func_str);
        m(x)=f;
        a=app.EditField.Value;
        b=app.EditField_2.Value;
        x_vals = a:0.1:b;
            y_vals =eval(m(x_vals));
            plot(app.UIAxes, x_vals, y_vals,'b');
            hold(app.UIAxes, 'on');
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)

        % 获取输入的函数字符串
        syms x;
        syms m(x);
        func_st= app.TextArea.Value;

        func_str=string(func_st);
        % 创建函数句柄
        f = eval(func_str);
        m(x)=f;
        a=app.EditField.Value;
        b=app.EditField_2.Value;
        
        f1=app.fafbSlider.Value;
        assume(x>a&x<b);
        sol_y=solve(f1==m,x);
        app.bTextArea.Value=string(sol_y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '函数图像')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '验证罗尔定理';
            app.fafbLabel = uilabel(app.UIFigure);
            app.fafbLabel.Text = {'选定f(a)'; '=f(b)的值'};
            app.fafbSlider = uislider(app.UIFigure);
            app.fafbSlider.Limits = [0 10];
            app.fafbSlider.Value = 0.5;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '输入区间左端点：';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '输入区间右端点：';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {'验证函数是否'; '满足罗尔定理'};
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.abLabel_2 = uilabel(app.UIFigure);
            app.abLabel_2.Text = {'已选取区间左端点作为a，'; '选取上述第几项作为b'};
            app.abSpinner = uispinner(app.UIFigure);
            app.abSpinner.Value = 1;
            app.abLabel = uilabel(app.UIFigure);
            app.abLabel.Text = 'b可取的值：';
            app.bTextArea = uitextarea(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '输入函数';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMG_0599(20240407-162045).PNG');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '菜单';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '绘制原图像';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '计算';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'若满足罗尔定理，则至少存在一个 ξ∈(a,b)，使得 f''(ξ)=0。'; '图形上表示为平行与x轴的直线'};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '罗尔定理';
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_4.Value = {'（1）在闭区间 [a,b] 上连续'; '（2）在开区间 (a,b) 内可导'; '（3）f(a)=f(b)，'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rolle_item

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end