classdef Start_page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Button_10  matlab.ui.control.Button
        Button_9   matlab.ui.control.Button
        Button_8   matlab.ui.control.Button
        Button_6   matlab.ui.control.Button
        Image      matlab.ui.control.Image
        Button_7   matlab.ui.control.Button
        Button_5   matlab.ui.control.Button
        Button_4   matlab.ui.control.Button
        Button_3   matlab.ui.control.Button
        Button_2   matlab.ui.control.Button
        Button     matlab.ui.control.Button
        TextArea   matlab.ui.control.TextArea
        Label_2    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
           delete(app.UIFigure);
            run C:\Users\谢雨良\Desktop\Taylor_item.mlapp;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app.UIFigure);
            run C:\Users\谢雨良\Desktop\curvature.mlapp;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
              delete(app.UIFigure);
            run C:\Users\谢雨良\Desktop\Rolle_item.mlapp;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            delete(app.UIFigure);
            run C:\Users\谢雨良\Desktop\Lagrange_item.mlapp;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
           delete(app.UIFigure);
            run C:\Users\谢雨良\Desktop\cauchy.mlapp;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            delete(app.UIFigure);
            run C:\Users\谢雨良\Desktop\test.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'高等数学可视化'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '泰勒展开';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '弧微分，曲率，密切圆';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '二重积分';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '微积分几何意义';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '罗尔中值定理';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '拉格朗日中值定理';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMG_0599(20240407-162045).PNG');
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '退出';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '柯西中值定理';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Text = '渐近线';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start_page

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end