classdef Taylor_item < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image3            matlab.ui.control.Image
        Image_2           matlab.ui.control.Image
        minxSpinner       matlab.ui.control.Spinner
        minxSpinnerLabel  matlab.ui.control.Label
        Button            matlab.ui.control.Button
        TextArea          matlab.ui.control.TextArea
        Label_6           matlab.ui.control.Label
        Image2            matlab.ui.control.Image
        Image             matlab.ui.control.Image
        Label_4           matlab.ui.control.Label
        lim_x             matlab.ui.control.Spinner
        Label_2           matlab.ui.control.Label
        Spinner           matlab.ui.control.Spinner
        Label_3           matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: EditField, Spinner
        function EditFieldValueChanged(app, event)
      cla(app.UIAxes);
     
      
            changedValue = event.Value;
            b=app.lim_x.Value;
            syms x;
            str=string(app.EditField.Value);
            g=eval(str);
            a=app.Spinner.Value;
            y1=taylor(g,x,'order',a);      
            app.TextArea.Value=string(taylor(g,x,'order',a));
            b=app.lim_x.Value;
            a=app.minxSpinner.Value
            x=a:0.1:b; 
            legend(app.UIAxes,'y1','g')
            y1=eval(y1);
            y2=eval(g);
            plot(app.UIAxes,x,y1,'g','DisplayName','拟合函数');
            hold(app.UIAxes,"on");
            plot(app.UIAxes,x,y2,'r','DisplayName','原函数');
        end

        % Callback function
        function SpinnerValueChanging(app, event)
      cla(app.UIAxes);
     
      
            changedValue = event.Value;
            b=app.lim_x.Value;
            syms x;
            str=string(app.EditField.Value);
            g=eval(str);
            a=app.Spinner.Value;
            y1=taylor(g,x,'order',a);      
            app.TextArea.Value=string(taylor(g,x,'order',a));
            b=app.lim_x.Value;
            a=app.minxSpinner.Value
            x=a:0.1:b; 
            legend(app.UIAxes,'y1','g')
            y1=eval(y1);
            y2=eval(g);
            plot(app.UIAxes,x,y1,'g','DisplayName','拟合函数');
            hold(app.UIAxes,"on");
            plot(app.UIAxes,x,y2,'r','DisplayName','原函数');
        end

        % Value changing function: lim_x
        function lim_xValueChanging(app, event)
        cla(app.UIAxes);
            changingValue = event.Value;
            syms x;
            str=string(app.EditField.Value);
            g=eval(str);
            a=double(app.Spinner.Value);
            b=app.lim_x.Value;
            y1=taylor(g,x,'order',a);      
            app.TextArea.Value=string(taylor(g,x,'order',a));
            a=app.minxSpinner.Value
            x=a:0.1:b; 
            legend(app.UIAxes,'y1','g')
            y1=eval(y1);
            y2=eval(g);
            plot(app.UIAxes,x,y1,'g','DisplayName','拟合函数');
            hold(app.UIAxes,"on");
            plot(app.UIAxes,x,y2,'r','DisplayName','原函数');
       
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            delete(app.UIFigure);
            run C:\Users\谢雨良\Desktop\Start_page.mlapp
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '绘图区域')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.YLim = [-5 5];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes.YTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '请输入需要泰勒展开的函数';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '展开次数';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.Value = 5;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'max(x)';
            app.lim_x = uispinner(app.UIFigure);
            app.lim_x.ValueChangingFcn = createCallbackFcn(app, @lim_xValueChanging, true);
            app.lim_x.Limits = [1 Inf];
            app.lim_x.Value = 1;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '泰勒展开';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '展开式：';
            app.TextArea = uitextarea(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '菜单';
            app.minxSpinnerLabel = uilabel(app.UIFigure);
            app.minxSpinnerLabel.Text = 'min(x)';
            app.minxSpinner = uispinner(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'IMG_0599(20240407-162045).PNG');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'C69023B749DFF5D21B5989E6D8B49DB3.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Taylor_item

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end