classdef cauchy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label_6          matlab.ui.control.Label
        TextArea_2       matlab.ui.control.TextArea
        gxTextArea       matlab.ui.control.TextArea
        gxTextAreaLabel  matlab.ui.control.Label
        TextArea         matlab.ui.control.TextArea
        ufxvgxButton     matlab.ui.control.Button
        fxgxButton       matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Image            matlab.ui.control.Image
        Label_4          matlab.ui.control.Label
        fxTextArea       matlab.ui.control.TextArea
        Slider           matlab.ui.control.Slider
        bEditField       matlab.ui.control.NumericEditField
        Label_3          matlab.ui.control.Label
        aEditField       matlab.ui.control.NumericEditField
        Label_2          matlab.ui.control.Label
        Label_5          matlab.ui.control.Label
        Button           matlab.ui.control.StateButton
        UIAxes           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)        
       function results = line_tangent(app,x0,m,a)
            syms x;
            y0=eval(m(x0));
            k=diff(m,x);
            k=eval(k(x0));
            b=y0-k*x0;
            results=k*a+b;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
       cla(app.UIAxes)
            syms x;
        syms f(x);
        syms g(x);
        func_st1= app.fxTextArea.Value;
         func_st2= app.gxTextArea.Value;
        func_str1=string(func_st1);
         func_str2=string(func_st2);
        % 创建函数句柄
        m =eval(func_str1);
f(x)=m;
n=eval(func_str2);
g(x)=n;
   a=app.aEditField.Value;
        b=app.bEditField.Value;       


% 生成一系列 x 值
x_values = linspace(a,b,1000); % 生成从 -10 到 10 的 100 个均匀间隔的 x 值

% 计算 f(x)
f_values = f(x_values);

% 计算 g(f(x))
g_values = g(x_values);

% 绘制曲线
plot(app.UIAxes,f_values, g_values,'g');
xlabel(app.UIAxes,'f(x)');
ylabel(app.UIAxes,'g(x)');
%连接f(a),g(a)与f(b),g(b)
   hold(app.UIAxes, 'on');

plot(app.UIAxes,[f(a),f(b)],[g(a),g(b)],'-o')
 hold(app.UIAxes, 'on');
grid(app.UIAxes,"on");
syms x y c
x1=solve( m == y, x);
h(y)=g(x1);
   df = diff(h,y);
       w=(g(a)-g(b))/(f(a)-f(b));
       u=min(f_values);
       v=max(f_values);
       assume(y>u&y<v);
       sol = solve ( df==w, y);
sol_double = double(sol);
            y0=h(sol_double);
            k=diff(h,y);
            k=k(sol_double);
            b=y0-k*sol_double;
            g=k*c+b;

c=sol-1:0.01:sol+1;
yc=eval(g);
  plot(app.UIAxes, c, yc,'r');


% % 计算导数
% df_dx = diff(m, x); % f 对 x 的导数
% dg_dx = diff(n, x); % g 对 x 的导数
% 
% % 计算导数之间的比率，并求倒数
% du_dx = 1 / df_dx; % x 对 v 的导数
% dv_du = dg_dx * du_dx; % u 对 v 的导数
% w=(g(a)-g(b))/(f(a)-f(b));
%  assume(x>a&x<b);
% sol = solve ( dv_du==w, x);
% sol_double = double(sol);
% u=sol_double;
% v=g(sol_double);
% e=v-w*u;
% x_values = u-1:0.01:u+1;
% y=w*x_values+e;
% 
% 
% plot(app.UIAxes,x,y,'g');
     
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
    
                      fxgxButtonPushed(app, event)
                      syms x;
        syms m(x);
        syms n(x);
        func_st1= app.fxTextArea.Value;
         func_st2= app.gxTextArea.Value;
        func_str1=string(func_st1);
         func_str2=string(func_st2);
        % 创建函数句柄
        f = eval(func_str1);
        p = eval(func_str2);
        m(x)=f;
        n(x)=p;
            value = app.Slider.Value;
            syms c; 
            x1=app.Slider.Value;
g=line_tangent(app,x1,m,c);
q=line_tangent(app,x1,n,c);
c=x1-1:0.01:x1+1;
            yc1=eval(g);
            yc2=eval(q);
   
                   scatter(app.UIAxes, x1, m(x1), 'g', 'filled');
                 scatter(app.UIAxes, x1, n(x1), 'g', 'filled');
                  plot(app.UIAxes, c, yc1,'r');
     plot(app.UIAxes, c, yc2,'r');


        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app.UIFigure);
            run C:\Users\谢雨良\Desktop\Start_page.mlapp
        end

        % Button pushed function: fxgxButton
        function fxgxButtonPushed(app, event)
      cla(app.UIAxes)
       syms x;
        syms m(x);
        syms n(x);
        func_st1= app.fxTextArea.Value;
         func_st2= app.gxTextArea.Value;
        func_str1=string(func_st1);
         func_str2=string(func_st2);
        % 创建函数句柄
        f = eval(func_str1);
        g=eval(func_str2);
        m(x)=f;
        n(x)=g;
        a=app.aEditField.Value;
        b=app.bEditField.Value;
        x_vals = a:0.1:b;
            y1_vals =eval(m(x_vals));
            y2_vals =eval(n(x_vals));
            plot(app.UIAxes, x_vals, y1_vals,'b');
             hold(app.UIAxes, 'on');
             plot(app.UIAxes, x_vals, y2_vals,'b');
            hold(app.UIAxes, 'on');
            
        end

        % Button pushed function: ufxvgxButton
        function ufxvgxButtonPushed(app, event)
       cla(app.UIAxes)
            syms x;
        syms m(x);
        syms n(x);
        func_st1= app.fxTextArea.Value;
         func_st2= app.gxTextArea.Value;
        func_str1=string(func_st1);
         func_str2=string(func_st2);
        % 创建函数句柄
        m =eval(func_str1);
f(x)=m;
n=eval(func_str2);
g(x)=n;
   a=app.aEditField.Value;
        b=app.bEditField.Value;       
x= linspace(a, b, 1000);

% 计算 f(x) 和 g(x) 的值
f1 = f(x);
g1 = g(x);

% 绘制 f(x) 关于 g(x) 的图像

plot(app.UIAxes,f1, g1, 'b');
xlabel(app.UIAxes,'f(x)');
ylabel(app.UIAxes,'g(x)');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '函数图像')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '柯西定理可视化';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = {'验证函数是否'; '满足柯西中值定理 '};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '输入区间左端点a：';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '输入区间右端点b：';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Value = 3;
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.fxTextArea = uitextarea(app.UIFigure);
            app.fxTextArea.Value = {'2*x'};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '输入函数f(x)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMG_0599(20240407-162045).PNG');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '菜单';
            app.fxgxButton = uibutton(app.UIFigure, 'push');
            app.fxgxButton.ButtonPushedFcn = createCallbackFcn(app, @fxgxButtonPushed, true);
            app.fxgxButton.Text = '绘制f(x)，g(x)图像';
            app.ufxvgxButton = uibutton(app.UIFigure, 'push');
            app.ufxvgxButton.ButtonPushedFcn = createCallbackFcn(app, @ufxvgxButtonPushed, true);
            app.ufxvgxButton.Text = {'绘制方程'; 'u=f(x)'; 'v=g(x)的图像'};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'若函数f(x),g(x)在'; '(1)[a,b]上连续'; '(2)(a,b)内可导'; '(3)g‘(x)在(a,b)恒不为0'};
            app.gxTextAreaLabel = uilabel(app.UIFigure);
            app.gxTextAreaLabel.Text = '输入函数g(x)';
            app.gxTextArea = uitextarea(app.UIFigure);
            app.gxTextArea.Value = {'x^2'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'若满足柯西中值定理，则至少存在一个 ξ∈(a,b)，使得[f(b)-f(a)]/[g(b)-g(a)]=f''(ξ)/g''(ξ)'; ''; '以f(x)作为横坐标，g(x)作为纵坐标画图，起点与终点的割线斜率即为f(b)-f(a)/g(b)-g(a)'; '而f''(ξ)/g''(ξ)为ξ点处的切线斜率，若切线与割线平行，即[f(b)-f(a)]/[g(b)-g(a)]=f''(ξ)/g''(ξ)'};
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '柯西中值定理';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cauchy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end