classdef curvature < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TextArea_3        matlab.ui.control.TextArea
        Button_2          matlab.ui.control.Button
        TextArea_2        matlab.ui.control.TextArea
        Image             matlab.ui.control.Image
        Button            matlab.ui.control.StateButton
        CheckBox_5        matlab.ui.control.CheckBox
        Radius_curvature  matlab.ui.control.NumericEditField
        Label_6           matlab.ui.control.Label
        curvature1        matlab.ui.control.NumericEditField
        Label_5           matlab.ui.control.Label
        CheckBox_4        matlab.ui.control.CheckBox
        CheckBox_3        matlab.ui.control.CheckBox
        CheckBox_2        matlab.ui.control.CheckBox
        CheckBox          matlab.ui.control.CheckBox
        Slider_2Label     matlab.ui.control.Label
        working_button    matlab.ui.control.StateButton
        Slider            matlab.ui.control.Slider
        Label_7           matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        target_field      matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        TextArea          matlab.ui.control.TextArea
        Image2            matlab.ui.control.Image
        main_painting     matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function b = tran(app,func)
            str=string(func);
            syms x;
            g=eval(str); %
            b=g;
        end

        function results = func2(app,x0,f,df,r)
            x1=x0-(r*df)/(1+df^2)^(1/2);
            y1=f+r/(1+df^2)^(1/2);
            results=[x1 y1];
        end

        function results = line_TwoPoint(app,x0,x1,y0,y1,x)

            k=(y1-y0)/(x1-x0);
            b=y0-k*x0;
            results=k*x+b;
        end

        function results = line_tangent(app,x0,m,a)
            syms x;
            y0=eval(m(x0));
            k=diff(m,x);
            k=eval(k(x0));
            b=y0-k*x0;
            results=k*a+b;
        end

        function results = Round_TwoPoint(app,xc,xc1,m,a)
            syms x;
            yc=eval(m(xc));
            yc1=eval(m(xc1));
            d=((xc-xc1)^2+(yc-yc1)^2)^(1/2);
            k=diff(m,x);
            kc=eval(k(xc));
            kc1=eval(k(xc1));
            a=atan(kc-kc1);
            r=d/a;
            results=[func2(app,xc,yc,kc,r) r];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: target_field
        function target_fieldValueChanged(app, event)

        end

        % Value changed function: working_button
        function working_buttonValueChanged(app, event)

            value = app.working_button.Value;
            cla(app.main_painting);

            func=string(app.target_field.Value);

            syms x;
            g = tran(app,func);  % 读入框中的函数

            syms x;
            dy=diff(g,x);
            dy2=diff(dy,x);

            x=app.Slider.Value;         %赋值

            k=eval(dy);                 %曲线切线斜率

            a=eval(dy);
            ds=sqrt(1+a*a);             %弧微分

            dq=dy2/(1+dy^2)^(3/2);        %曲率


            r=1/dq;                     %曲率圆的半径

            axis(app.main_painting,"equal") ;
            x=1:0.01:10;
            y1=eval(g);
            plot(app.main_painting,x,y1,'r','LineWidth',2,'linestyle','-','displayname','原函数');
            hold(app.main_painting,"on");   %原函数图像绘图


            syms m(x);
            m(x)=g;
            xa=app.Slider.Value;
            ya=eval(m(xa));
            syms a;
            y_RoundTangent=line_tangent(app,xa,m,a);
            a=1:0.1:10;
            y_RoundTangent=eval(y_RoundTangent);
            if(app.CheckBox_2.Value)
                plot(app.main_painting,a,y_RoundTangent,'displayname','曲线切线');    %曲线切线绘图
            end


            x0=xa;
            y0=ya;
            d(x)=r;
            cur(x)=dq;
            r1=eval(d(x0));
            dn1(x)=dy;
            ty=eval(cur(x0));
            app.curvature1.Value=ty;
            app.Radius_curvature.Value=r1;
            df=eval(dn1(x0));
            a=func2(app,x0,y0,df,r1);                       %求曲率圆

            if(app.CheckBox.Value)
                theta=0:pi/200:2*pi;
                x=a(1,1)+r1*sin(theta);
                y=a(1,2)+r1*cos(theta);
                plot(app.main_painting,x,y,'r','displayname','曲率圆');
            end%绘制曲率圆


            xc=app.Slider_2Label.Value(1,1);
            y3=eval(m(xc));

            xc1=app.Slider_2Label.Value(1,2);
            y4=eval(m(xc1));

            syms u;
            y5=line_TwoPoint(app,xc1,xc,y4,y3,u);
            u=0:0.1:10;
            y5=eval(y5);

            syms m(x);
            m(x)=g;
            syms a;
            y_XcTangent=line_tangent(app,xc,m,a);
            a=1:0.1:10;
            y_XcTangent=eval(y_XcTangent);
            if(app.CheckBox_4.Value)
                scatter(app.main_painting,xc,y3,'displayname','切点1');
                scatter(app.main_painting,xc1,y4,'displayname','切点2');
                plot(app.main_painting,u,y5,'g','displayname','切线1');
                plot(app.main_painting,a,y_XcTangent,'displayname','切线2'); %绘制两个点的切线
            end


            syms b;
            y_Xc1Tangent=line_TwoPoint(app,xc,xc1,y3,y4,b);
            b=1:0.1:10;
            y_Xc1Tangent=eval(y_Xc1Tangent);
            if(app.CheckBox_3.Value)
                plot(app.main_painting,b,y_Xc1Tangent,'displayname','两点拟合的直线');
            end
            legend(app.main_painting);

            s=Round_TwoPoint(app,xc,xc1,m,a);

            if(app.CheckBox_5.Value)
                theta=0:pi/200:2*pi;
                x=s(1,1)+s(1,3)*sin(theta);
                y=s(1,2)+s(1,3)*cos(theta);
                plot(app.main_painting,x,y,'g');
            end


        end

        % Callback function
        function Slider2ValueChanged(app, event)


        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Callback function
        function Slider2_2ValueChanging(app, event)
            changingValue = event.Value;
            xline(app.main_painting,app.Slider2_2.Value,'linewidth','2','linestyle','--');
        end

        % Callback function
        function Slider2_2ValueChanged(app, event)
            value = app.Slider2_2.Value;
            changingValue = event.Value;

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)

        end

        % Callback function
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;

        end

        % Callback function
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            cla(app.main_painting);
            working_buttonValueChanged(app,event);
            xline(app.main_painting,value);

        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            app.Slider_2.Value(1,1)=app.Slider.Value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
           delete(app.UIFigure);
           run C:\Users\谢雨良\Desktop\Start_page.mlapp
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.main_painting = uiaxes(app.UIFigure);
            title(app.main_painting, '绘图区域')
            xlabel(app.main_painting, 'X')
            ylabel(app.main_painting, 'Y')
            zlabel(app.main_painting, 'Z')
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '1F2D2FF45D0642D52EC66A005BA98E69.png');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'选定要展示的图像'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '请输入目标公式';
            app.target_field = uieditfield(app.UIFigure, 'text');
            app.target_field.ValueChangedFcn = createCallbackFcn(app, @target_fieldValueChanged, true);
            app.target_field.Value = 'sin(x)';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '弧微分，曲率，密切圆';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '选取点';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 10];
            app.Slider.Value = 3;
            app.working_button = uibutton(app.UIFigure, 'state');
            app.working_button.ValueChangedFcn = createCallbackFcn(app, @working_buttonValueChanged, true);
            app.working_button.Text = '画图';
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = '两点';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '曲率圆';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '曲线切线';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '两点拟合直线';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.Text = '两点切线';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '曲率：';
            app.curvature1 = uieditfield(app.UIFigure, 'numeric');
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '曲率半径：';
            app.Radius_curvature = uieditfield(app.UIFigure, 'numeric');
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.Text = '拟合曲率圆';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '端点值一致';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMG_0599(20240407-162045).PNG');
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '菜单';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'曲线上点M处的曲率的倒数，称作曲线在这点处的曲率半径，记作ρ。                                                                                                                                                                                                                     在点M处曲线的法线的某一侧上取一点D，使|DM| = ρ，并以D为圆心，以ρ为半径作圆。把这个圆称作曲线在点M处的曲率圆，把圆心D称做曲线在M处的曲率中心。'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = curvature

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end