classdef line < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TextArea_2       matlab.ui.control.TextArea
        Button_4         matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        bEditField       matlab.ui.control.NumericEditField
        bEditFieldLabel  matlab.ui.control.Label
        aEditField       matlab.ui.control.NumericEditField
        aEditFieldLabel  matlab.ui.control.Label
        Button_3         matlab.ui.control.StateButton
        Button_2         matlab.ui.control.StateButton
        Button           matlab.ui.control.StateButton
        TextArea         matlab.ui.control.TextArea
        Label            matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
      cla(app.UIAxes);
      value = app.Button.Value;
% 提示用户输入函数
syms x
f_str = string(app.TextArea.Value);

% 将用户输入的字符串转换为函数句柄
f(x)=eval(f_str)
a=app.aEditField.Value;
b=app.bEditField.Value;
% 定义 x 的范围
x = linspace(-10, 10, 1000);

% 计算函数值
y = f(x);

% 绘制函数图像
plot(app.UIAxes,x, y,'r');
hold(app.UIAxes,"on")
% 寻找函数的水平渐近线
a_line= limit(f, Inf);
b_line=limit(f,-Inf);
% 如果不是无穷大，则存在水平渐近线
u= ~isinf(a_line)&&~isnan(a_line);    
v= ~isinf(b_line)&&~isnan(b_line);
if u==1
    plot(app.UIAxes,x, a_line*ones(size(x)), 'b');
end
if v==1
    plot(app.UIAxes,x, b_line*ones(size(x)), 'b');
end
if u==0&&v==0
   errordlg('函数没有水平渐近线！','错误提示','error');
end
% 设置图形属性
xlabel(app.UIAxes,'x');
ylabel(app.UIAxes,'f(x)');
title(app.UIAxes,'渐近线');


        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
           
            cla(app.UIAxes);
            value = app.Button_2.Value;
syms x;
f_str = string(app.TextArea.Value);

% 将用户输入的字符串转换为函数句柄
f(x)=eval(f_str);
a=app.aEditField.Value;
b=app.bEditField.Value;
% 定义 x 的范围
x = linspace(-10, 10, 10000);

% 计算函数值
y = f(x);

% 绘制函数图像
plot(app.UIAxes,x, y,'r');
hold(app.UIAxes,"on");
a_line=zeros(1,100000)
% 寻找函数的垂直渐近线
c=1;
for i=a:0.01:b
    
    a_line(c)= limit(f, i);
    c=c+1;
end
M=find(a_line == NaN);
if isempty(M)
     errordlg('函数没有铅直渐近线！','错误提示','error');
else 
    x_1=M-(M-1)*0.01;
xline(app.UIAxes,x_1,'b')
end
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            value = app.Button_3.Value;
            cla(app.UIAxes);
      value = app.Button.Value;
% 提示用户输入函数
syms x
f_str = string(app.TextArea.Value);

% 将用户输入的字符串转换为函数句柄
f(x)=eval(f_str)
a=app.aEditField.Value;
b=app.bEditField.Value;
% 定义 x 的范围
x = linspace(-10, 10, 10000);

% 计算函数值
y = f(x);

% 绘制函数图像
plot(app.UIAxes,x, y,'r');
hold(app.UIAxes,"on")
            % 寻找函数的斜渐近线
if length(symvar(f)) == 1 % 确保只有一个自变量
    % 获取函数的最高次项的系数
    coeffs = fliplr(sym2poly(f));
    if length(coeffs) > 1
        slope = coeffs(1);
        disp(['Slope of slant asymptote: ', num2str(slope)]);
        % 绘制斜渐近线
        plot(x, slope * x, 'm--');
    else
        disp('函数没有斜渐近线！');
    end
else
    disp('函数不是单变量函数，无法找到斜渐近线！');
end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            run C:\Users\谢雨良\Desktop\Start_page.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-1 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '输入函数';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'sin(x)'};
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '水平渐近线';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '铅直渐近线';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '斜渐近线';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = '输入区间左端点a：';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = '输入区间右端点b：';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Value = 3;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '渐近线';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '菜单';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'水平渐近线：当x趋于无穷时，如果函数f(x)的极限存在且为某一常数c，那么y=c所表示的直线就是其水平渐近线。'; ''; '铅直渐近线：当x趋于某个值x0时，如果函数f(x)的极限为无穷大，那么x=x0就是其垂直渐近线。'; ''; '斜渐近线：当x趋于无穷时，如果函数f(x)/x的极限存在且不为0，记作k，同时f(x)-kx的极限存在且为b，那么y=kx+b就是其斜渐近线。'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = line

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end