classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TextArea          matlab.ui.control.TextArea
        Label_4           matlab.ui.control.Label
        Image             matlab.ui.control.Image
        Button            matlab.ui.control.Button
        EditField2        matlab.ui.control.NumericEditField
        EditField2Label   matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
        EditFieldLabel    matlab.ui.control.Label
        yEditField_min    matlab.ui.control.NumericEditField
        yLabel_2          matlab.ui.control.Label
        xEditField_min    matlab.ui.control.NumericEditField
        xLabel_2          matlab.ui.control.Label
        yEditField        matlab.ui.control.NumericEditField
        yLabel            matlab.ui.control.Label
        xEditField        matlab.ui.control.NumericEditField
        xLabel            matlab.ui.control.Label
        Slider_precision  matlab.ui.control.Slider
        Label_2           matlab.ui.control.Label
        working_button    matlab.ui.control.StateButton
        target_field      matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        Label_5           matlab.ui.control.Label
        main_painting_2   matlab.ui.control.UIAxes
        main_painting     matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function b = tran(app,func)
            str=string(func);
            syms x y;
            g=eval(str); %
            b=g;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: target_field
        function target_fieldValueChanged(app, event)

        end

        % Value changed function: working_button
        function working_buttonValueChanged(app, event)

            value = app.working_button.Value;
            cla(app.main_painting);

            func=string(app.target_field.Value);

            syms z1(x,y) x1 y1;
            g = tran(app,func);  % 读入框中的函数

            a=[];
            b=[]
            z1(x,y)=g;
            [x,y]= meshgrid(app.xEditField_min.Value:app.Slider_precision.Value:app.xEditField.Value,app.yEditField_min.Value:app.Slider_precision.Value:app.yEditField.Value);
            z3=eval(z1(x,y));
            vs=app.Slider_precision.Value^2*z3;
            
            vs=sum(sum(vs));
            app.EditField2.Value=vs;

            x=[]
            for i=app.xEditField_min.Value:app.Slider_precision.Value:app.xEditField.Value
                x=horzcat(x,i)
            end

            bar3(app.main_painting,x,z3,1,'r')
            hold(app.main_painting,"on")
            [x,y]= meshgrid(app.xEditField_min.Value:0.1:app.xEditField.Value,app.yEditField_min.Value:0.1:app.yEditField.Value);
            z2=eval(z1(x,y));
            surf(app.main_painting_2,x,y,z2)
            gs=string(g)
            gs="@(x,y)"+gs;
           syms x y;
           g=str2func(gs)
            app.EditField.Value = quad2d(g,app.xEditField_min.Value,app.xEditField.Value,app.yEditField_min.Value,app.yEditField.Value)


        end

        % Callback function
        function Slider2ValueChanged(app, event)


        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Callback function
        function Slider2_2ValueChanging(app, event)

        end

        % Callback function
        function Slider2_2ValueChanged(app, event)


        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)

        end

        % Callback function
        function Slider_2ValueChanged(app, event)

        end

        % Callback function
        function ButtonValueChanged(app, event)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            delete(app.UIFigure);
            run C:\Users\谢雨良\Desktop\Start_page.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.main_painting = uiaxes(app.UIFigure);
            title(app.main_painting, '绘图区域')
            xlabel(app.main_painting, 'X')
            ylabel(app.main_painting, 'Y')
            zlabel(app.main_painting, 'Z')
            app.main_painting_2 = uiaxes(app.UIFigure);
            title(app.main_painting_2, '绘图区域')
            xlabel(app.main_painting_2, 'X')
            ylabel(app.main_painting_2, 'Y')
            zlabel(app.main_painting_2, 'Z')
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '请输入目标公式';
            app.target_field = uieditfield(app.UIFigure, 'text');
            app.target_field.ValueChangedFcn = createCallbackFcn(app, @target_fieldValueChanged, true);
            app.target_field.Value = 'sin(x)+cos(y)';
            app.working_button = uibutton(app.UIFigure, 'state');
            app.working_button.ValueChangedFcn = createCallbackFcn(app, @working_buttonValueChanged, true);
            app.working_button.Text = '画图';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '切割精度';
            app.Slider_precision = uislider(app.UIFigure);
            app.Slider_precision.Limits = [0 2];
            app.Slider_precision.Value = 0.1;
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x最大值：';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.Value = 5;
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y最大值';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.Value = 5;
            app.xLabel_2 = uilabel(app.UIFigure);
            app.xLabel_2.Text = 'x最小值：';
            app.xEditField_min = uieditfield(app.UIFigure, 'numeric');
            app.xEditField_min.Value = 0.3;
            app.yLabel_2 = uilabel(app.UIFigure);
            app.yLabel_2.Text = 'y最小值';
            app.yEditField_min = uieditfield(app.UIFigure, 'numeric');
            app.yEditField_min.Value = 0.3;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '菜单';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMG_0599(20240407-162045).PNG');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '二重积分';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'二重积分是二元函数在空间上的积分，同定积分类似，是某种特定形式的和的极限。本质是求曲顶柱体体积。重积分有着广泛的应用，可以用来计算曲面的面积，平面薄片重心等。平面区域的二重积分可以推广为在高维空间中的（有向）曲面上进行积分，称为曲面积分'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end