classdef Cross_Multiplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TextArea          matlab.ui.control.TextArea
        Image             matlab.ui.control.Image
        Button_2          matlab.ui.control.Button
        Panel_2           matlab.ui.container.Panel
        EditField_5       matlab.ui.control.EditField
        Label_3           matlab.ui.control.Label
        EditField_3       matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        Panel             matlab.ui.container.Panel
        EditField_2       matlab.ui.control.EditField
        EditField_2Label  matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        EditField_4       matlab.ui.control.EditField
        Button            matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function isThreeDimensional = is3Dvector(~,vector)
            % 获取矩阵的尺寸信息
            vectorSize = size(vector);

            % 判断矩阵是否为三维向量
            if isvector(vector) && vectorSize(2) == 3
                isThreeDimensional = true;
            else
                isThreeDimensional = false;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            %赋值
            value_2 = app.EditField_2.Value;
            value_1 = app.EditField.Value;
            %向量化
            if isempty(value_1)
                vec_1=[0,0,0];
            else
            vec_1=str2num(value_1);
            end
            vec_2=str2num(value_2);
            %判断输入格式是否正确
            if app.is3Dvector(vec_1) && app.is3Dvector(vec_2)
            %计算叉乘
            vec_3=cross(vec_1,vec_2);
            %输出
            app.EditField_3.Value=num2str(vec_3);
            %计算模
            app.EditField_5.Value=num2str(norm(vec_3));
            else
                msgbox('向量格式错误，请输入正确的三维向量','WARN','error');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            legend(app.UIAxes,'off');
            %更新坐标区域
            cla(app.UIAxes);
            %赋值
            value_1 = app.EditField.Value;
            value_2 = app.EditField_2.Value;
            vec_1=str2num(value_1);
            vec_2=str2num(value_2);

            %判断格式
            a=vec_1./vec_2;
            if a(1)==a(2)&&a(2)==a(3)
                msgbox('请输入两个非零非共线三维向量','tips','warn');
            end

            %计算叉乘
            C=cross(vec_1,vec_2);
            %计算模
            app.EditField_5.Value=num2str(round(norm(C),2));

            %选择平面上一点
            p=[0,0,0];

            %点法式方程
            if C(3)~=0
                [X,Y]=meshgrid(-10:0.1:10,-10:0.1:10);
                Z=(-C(1).*(X-p(1))-C(2).*(Y-p(2)))./C(3)+p(3);
                surf(app.UIAxes,X,Y,Z,"FaceColor","none","EdgeColor",'y','FaceAlpha',0.5);
            elseif C(3)==0&&C(2)~=0
                [X,Z]=meshgrid(-10:0.1:10,-10:0.1:10);
                Y=-(C(1).*(X-p(1)))./C(2)+p(2);
                surf(app.UIAxes,X,Y,Z,"FaceColor","none","EdgeColor",'y','FaceAlpha',0.5);
            elseif C(3)==0&&C(1)~=0
                [Z,Y]=meshgrid(-10:0.1:10,-10:0.1:10);
                X=-(C(2).*(Y-p(2)))./C(1)+p(1);
                surf(app.UIAxes,X,Y,Z,"FaceColor","none","EdgeColor",'y','FaceAlpha',0.5);
            end

            %画出向量
            hold (app.UIAxes,'on');
            q1=quiver3(app.UIAxes,0,0,0,C(1),C(2),C(3),'off','r','LineWidth',2,'AutoScale',2,'MaxHeadSize',0.2);
            q2=quiver3(app.UIAxes,0,0,0,vec_1(1),vec_1(2),vec_1(3),'off','k','LineWidth',2,'AutoScale',2,'MaxHeadSize',0.2);
            q3=quiver3(app.UIAxes,0,0,0,vec_2(1),vec_2(2),vec_2(3),'off','g','LineWidth',2,'AutoScale',2,'MaxHeadSize',0.2);
            

            %颜色填充
            vec_3=vec_1+vec_2;
            x=[p(1),vec_1(1)+p(1),vec_3(1),vec_2(1)+p(1)];
            y=[p(2),vec_1(2)+p(2),vec_3(2),vec_2(2)+p(2)];
            z=[p(3),vec_1(3)+p(3),vec_3(3),vec_2(3)+p(3)];
            f1=fill3(app.UIAxes,x,y,z,'b','FaceAlpha', 0.9);
            legend(app.UIAxes,[q1,q2,q3,f1],{'结果向量','向量1','向量2','所围平面'});
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\UI_figure.mlapp;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
             %赋值
            value_2 = app.EditField_2.Value;
            value_1 = app.EditField.Value;
            %向量化
            if isempty(value_2)
                vec_2=[0,0,0];
            else
            vec_2=str2num(value_2);
            end
            vec_1=str2num(value_1);
            %判断输入格式是否正确
            if app.is3Dvector(vec_1) && app.is3Dvector(vec_2)
            %计算叉乘
            vec_3=cross(vec_1,vec_2);
            %输出
            app.EditField_3.Value=num2str(vec_3);
            %计算模
            app.EditField_5.Value=num2str(norm(vec_3));
            else
                msgbox('向量格式错误，请输入正确的三维向量','WARN','error');
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '绘图区域')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.ZLim = [-10 10];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '生成图像';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Value = '向量叉乘';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '输入区域';
            app.Label = uilabel(app.Panel);
            app.Label.Tooltip = {'请输入三维向量'};
            app.Label.Text = '输入向量1';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tooltip = {'请输入非零非共线三维向量'};
            app.EditField_2Label = uilabel(app.Panel);
            app.EditField_2Label.Tooltip = {'请输入非零非共线三维向量'};
            app.EditField_2Label.Text = '输入向量2';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Tooltip = {'请输入非零非共线三维向量'};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '输入区域';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '输出向量';
            app.EditField_3 = uieditfield(app.Panel_2, 'text');
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '输出向量的模';
            app.EditField_5 = uieditfield(app.Panel_2, 'text');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '菜单';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '院徽.jpg');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'向量叉乘也称为向量的外积在图形中形成的外积向量的大小表示为两个向量所形成的平行四边形的面积的大小，方向为垂直于所张成的平面。'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cross_Multiplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end