classdef EigenVector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Image        matlab.ui.control.Image
        TextArea_3   matlab.ui.control.TextArea
        Panel_2      matlab.ui.container.Panel
        TextArea     matlab.ui.control.TextArea
        Label_3      matlab.ui.control.Label
        EditField_2  matlab.ui.control.EditField
        Label_2      matlab.ui.control.Label
        Button_2     matlab.ui.control.StateButton
        Panel        matlab.ui.container.Panel
        EditField    matlab.ui.control.EditField
        Label        matlab.ui.control.Label
        EditField_3  matlab.ui.control.EditField
        Slider       matlab.ui.control.Slider
        TextArea_2   matlab.ui.control.TextArea
        CheckBox     matlab.ui.control.CheckBox
        Button       matlab.ui.control.StateButton
        UIAxes       matlab.ui.control.UIAxes
    end


    methods (Access = public)

        function isTwoByTwo = is2x2Matrix(~,A)
            % is2x2Matrix 判断一个矩阵是否为 2x2 矩阵

            [rows, cols] = size(A);

            if rows == 2 && cols == 2
                isTwoByTwo = true;
            else
                isTwoByTwo = false;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            cla(app.UIAxes);
            %获取输入矩阵
            value = app.EditField.Value;
            A=str2num(value);
            %判断矩阵格式
            a=app.is2x2Matrix(A);
            if a~=1
                msgbox('线性变换矩阵输入错误，请按照提示输入正确的矩阵格式','WARN','error');
            end
            %计算特征值与特征向量
            [D,V]=eig(A);
            %特征值
            v=diag(V);
            app.EditField_2.Value=num2str(round(v',2));
            %特征向量
            app.TextArea.Value=mat2str(round(D,2));
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            cla(app.UIAxes);
            %获取输入矩阵
            value = app.EditField.Value;
            A=str2num(value);
            %判断矩阵格式
            a=app.is2x2Matrix(A);
            if a~=1
                msgbox('线性变换矩阵输入错误，请按照提示输入正确的矩阵格式','WARN','error');
            end
            %计算特征值与特征向量
            [D,V]=eig(A);
            %特征值
            v=diag(V);
            app.EditField_2.Value=num2str(round(v',2));
            %特征向量
            app.TextArea.Value=mat2str(round(D,2));
            %画出特征向量
            %起始点
            s=[0,0];
            %获取特征向量
            v1=[D(1),D(2)];
            v2=[D(3),D(4)];
            quiver(app.UIAxes,s(1),s(2),v1(1),v1(2),'LineWidth',1.3,'linestyle','-');
            hold (app.UIAxes,'on');
            quiver(app.UIAxes,s(1),s(2),v2(1),v2(2),'LineWidth',1.3,'linestyle','-');
            %画出变化后特征向量
            D1=A*D;
            v1_1=[D1(1),D1(2)];
            v2_1=[D1(3),D1(4)];
            quiver(app.UIAxes,s(1),s(2),v1_1(1),v1_1(2),'linestyle','--','LineWidth',2.5);
            hold (app.UIAxes,'on');
            quiver(app.UIAxes,s(1),s(2),v2_1(1),v2_1(2),'linestyle','--','LineWidth',2.5);

            % %画出测试向量
            % x=app.Slider.Value;
            % V=[cosd(x),sind(x)];
            % quiver(app.UIAxes,s(1),s(2),V(1),V(2),2,'-','LineWidth',1.5);
            % V1=A*V';
            % quiver(app.UIAxes,s(1),s(2),V1(1),V1(2),2,'--','LineWidth',1.5);
            %标签
            legend(app.UIAxes,'特征向量一','特征向量二','线性变换向量一','线性变换向量二','演示向量V','线性变换演示向量V');
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            legend(app.UIAxes,'off');
            cla(app.UIAxes);
            %获取输入矩阵
            value = app.EditField.Value;
            A=str2num(value);
            %判断矩阵格式
            a=app.is2x2Matrix(A);
            if a~=1
                msgbox('线性变换矩阵输入错误，请按照提示输入正确的矩阵格式','WARN','error');
            end
            %计算特征值与特征向量
            [D,V]=eig(A);
            %特征值
            v=diag(V);
            app.EditField_2.Value=num2str(round(v',2));
            %特征向量
            app.TextArea.Value=mat2str(round(D,2));
            %画出特征向量
            %起始点
            s=[0,0];
            %获取特征向量
            v1=[D(1),D(2)];
            v2=[D(3),D(4)];
            quiver(app.UIAxes,s(1),s(2),v1(1),v1(2),'-','LineWidth',1.3);
            hold (app.UIAxes,'on');
            quiver(app.UIAxes,s(1),s(2),v2(1),v2(2),'-','LineWidth',1.3);
            %画出变化后特征向量
            D1=A*D;
            v1_1=[D1(1),D1(2)];
            v2_1=[D1(3),D1(4)];
            quiver(app.UIAxes,s(1),s(2),v1_1(1),v1_1(2),'--','LineWidth',2.5);
            hold (app.UIAxes,'on');
            quiver(app.UIAxes,s(1),s(2),v2_1(1),v2_1(2),'--','LineWidth',2.5);

            %画出测试向量
            x=app.Slider.Value;
            V=[cosd(x),sind(x)];
            quiver(app.UIAxes,s(1),s(2),V(1),V(2),'-','LineWidth',1.3);
            V1=A*V';
            quiver(app.UIAxes,s(1),s(2),V1(1),V1(2),'--','LineWidth',2.5);
            %标签
            legend(app.UIAxes,'特征向量一','特征向量二','线性变换向量一','线性变换向量二','演示向量V','线性变换演示向量V');
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value
                cla(app.UIAxes);
                legend(app.UIAxes,'off');
                %获取输入矩阵
                value = app.EditField.Value;
                A=str2num(value);
                %对演示向量进行线性变换
                x=app.Slider.Value;
                V=[cosd(x),sind(x)];

                t=-1:0.1:1;
                x0=t'*ones(1,21);
                for k=0:0.05:1
                    T=[(A(1)-1)*k+1,A(3)*k;A(2)*k,(A(4)-1)*k+1];
                    x1=T(1)*x0'+T(3)*x0 ;
                    y1=T(2)*x0'+T(4)*x0 ;
                    x2=T(1)*x0 +T(3)*x0';
                    y2=T(2)*x0 +T(4)*x0';
                    plot(app.UIAxes,x1,y1,'color',[0.4,0.4,0.4],'LineStyle','-');
                    hold (app.UIAxes,'on');
                    plot(app.UIAxes,x2,y2,'color',[0.4,0.4,0.4],'LineStyle','-');
                    %起始点坐标
                    s=[0,0];
                    %对向量做线性变换
                    S1=T*V';
                    quiver(app.UIAxes,s(1),s(2),S1(1),S1(2),'linestyle','-','LineWidth',2.5,'Color',[0.5,0.6,0.8]);
                    hold (app.UIAxes,'off');

                    
                    %生成动图，初状态作为循环，初末状态停止1秒，其他停止0.1秒.
                    im=frame2im(getframe(app.UIAxes));
                    [I,map]=rgb2ind(im,20);
                    if (k==0)
                        imwrite(I,map,'linear_trans_gif.gif','gif','LoopCount',inf,'DelayTime',1);
                    elseif (k==1)
                        imwrite(I,map,'linear_trans_gif.gif','gif','WriteMode','append','DelayTime',1);
                    else
                        imwrite(I,map,'linear_trans_gif.gif','gif','WriteMode','append','DelayTime',0.05);
                    end
                end
                %画出原向量
                q1=quiver(app.UIAxes,s(1),s(2),V(1),V(2),'linestyle','--','LineWidth',1.3,'color','k');
                %添加图例
                hold (app.UIAxes,'on');
                V1=A*V';
                q2=quiver(app.UIAxes,s(1),s(2),V1(1),V1(2),'linestyle','-','LineWidth',2.5,'Color',[0.5,0.6,0.8]);
                legend(app.UIAxes,[q1,q2],{'变换前向量','变换后向量'});
            else
                cla(app.UIAxes);
                legend(app.UIAxes,'off');

                %获取输入矩阵
                value = app.EditField.Value;
                A=str2num(value);
                %计算特征值与特征向量
                [D,~]=eig(A);
                %画出特征向量
                %起始点
                s=[0,0];
                %画出特征向量
                v1=[D(1),D(2)];
                v2=[D(3),D(4)];
                quiver(app.UIAxes,s(1),s(2),v1(1),v1(2),'-','LineWidth',1.3);
                hold (app.UIAxes,'on');
                quiver(app.UIAxes,s(1),s(2),v2(1),v2(2),'-','LineWidth',1.3);
                %画出变化后特征向量
                D1=A*D;
                v1_1=[D1(1),D1(2)];
                v2_1=[D1(3),D1(4)];
                quiver(app.UIAxes,s(1),s(2),v1_1(1),v1_1(2),'--','LineWidth',2.5);
                hold (app.UIAxes,'on');
                quiver(app.UIAxes,s(1),s(2),v2_1(1),v2_1(2),'--','LineWidth',2.5);

                %画出演示向量
                x=app.Slider.Value;
                V=[cosd(x),sind(x)];
                quiver(app.UIAxes,s(1),s(2),V(1),V(2),'-','LineWidth',1.3);
                V1=A*V';
                quiver(app.UIAxes,s(1),s(2),V1(1),V1(2),'--','LineWidth',2.5);
                %标签
                legend(app.UIAxes,'特征向量一','特征向量二','线性变换向量一','线性变换向量二','演示向量V','线性变换演示向量V');
            end

        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\UI_figure.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '绘图区域')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.ZLim = [-10 10];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.ZTick = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '生成图像';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '显示向量线性变换过程';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'演示 向量V'; '旋转角度'};
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 360];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = '特征向量';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '输入区域';
            app.Label = uilabel(app.Panel);
            app.Label.Tooltip = {'请输入2x2矩阵'};
            app.Label.Text = '线性变换矩阵';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tooltip = {'请输入2x2矩阵'};
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '菜单';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '输出区域';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '特征值';
            app.EditField_2 = uieditfield(app.Panel_2, 'text');
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Tooltip = {'每一列代表一个特征向量，与下面的特征值相对应'};
            app.Label_3.Text = '特征向量';
            app.TextArea = uitextarea(app.Panel_2);
            app.TextArea.Tooltip = {'每一列代表一个特征向量，与下面的特征值相对应'};
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'  考虑特征值为实数的情况. 当特征值为实数时, 特征向量是线性变换前后方向不变或相反的非零向量, 特征值是特征向量有向长度的变化倍率。'; '  特征值为负数，表示线性变换前后方向改变；特征值为正数，表示线性变换前后，方向不变。'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '院徽.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EigenVector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end