classdef Equation_Set < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Image            matlab.ui.control.Image
        Button_2         matlab.ui.control.StateButton
        EditField_2      matlab.ui.control.EditField
        EditField        matlab.ui.control.EditField
        TextArea_2       matlab.ui.control.TextArea
        Panel_2          matlab.ui.container.Panel
        TextArea         matlab.ui.control.TextArea
        Label            matlab.ui.control.Label
        Button           matlab.ui.control.StateButton
        Panel            matlab.ui.container.Panel
        bEditField       matlab.ui.control.EditField
        bEditFieldLabel  matlab.ui.control.Label
        AEditField       matlab.ui.control.EditField
        AEditFieldLabel  matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function isThreeDimensional = is3Dmatrix(~,matrix)
            % 获取矩阵的尺寸信息
            matrixSize = size(matrix);

            % 判断矩阵是否为nx3矩阵
            if ismatrix(matrix) && matrixSize(2) == 3
                isThreeDimensional = true;
            else
                isThreeDimensional = false;
            end
        end
        
        function isTwoDimensional = is2Dmatrix(~,matrix)
            % 获取矩阵的尺寸信息
            matrixSize = size(matrix);

            % 判断矩阵是否为nx2矩阵
            if ismatrix(matrix) && matrixSize(2) == 2
                isTwoDimensional = true;
            else
                isTwoDimensional = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
       cla(app.UIAxes);
       legend(app.UIAxes,'off');
       %获取系数矩阵A，及常数向量b
       value1=app.AEditField.Value;
       value2=app.bEditField.Value;
       A=str2num(value1);
       b=str2num(value2);
       %判断方程组形式
       A1=size(A);
       b1=size(b);
       A2=app.is2Dmatrix(A);
       A3=app.is3Dmatrix(A);
       if A1(1)==b1(1)
           if A2==1
               %画出二元方程组
               for i=1:A1(1)
                   hold (app.UIAxes,'on');
                   B=[A(i,1),A(i,2)];
                   if B(2)~=0
                       x=linspace(-10,10,500);
                       y=(b(i)-B(1)*x)/B(2);
                       plot(app.UIAxes,x,y,'-');
                   elseif B(1)~=0
                       y=linspace(-10,10,500);
                       x=ones(size(y))*b(i)/B(1);
                       plot(app.UIAxes,x,y,'-');
                   else
                       msgbox('系数矩阵A输入错误，请按照提示输入正确的矩阵格式','WARN','error');
                   end
               end
               %添加图例
               legend(app.UIAxes,'直线一','直线二','直线三','直线四','直线五','直线六','直线七','直线八');

               %求解二元方程组
               Ab=[A,b];
               if rank(A)~=rank(Ab)
                   str='方程组无解';
                   app.TextArea.Value=str;
               elseif rank(A)==1
                   str='方程组有无穷多解，解在一条直线上';
                   app.TextArea.Value=str;
               else
                   X=A\b;
                   str=['方程组有唯一解，解为：','x=',num2str(X(1)),' ', 'y=', num2str(X(2))];
                   app.TextArea.Value=str;
               end
              
           elseif A3==1
               %画出三元方程组
               for i=1:A1(1)
                   hold (app.UIAxes,'on');
                   C=[A(i,1),A(i,2),A(i,3)];
                   %点法式方程
                   if C(3)~=0
                       [X,Y]=meshgrid(linspace(-10,10,500),linspace(-10,10,500));
                       Z=(-C(1).*X-C(2).*Y+b(i))./C(3);
                       surf(app.UIAxes,X,Y,Z,'edgeColor','none','FaceAlpha',0.2,'FaceColor','r');
                   elseif C(3)==0&&C(2)~=0
                       [X,Z]=meshgrid(linspace(-10,10,500),linspace(-10,10,500));
                       Y=(-C(1).*X+b(i))./C(2);
                       surf(app.UIAxes,X,Y,Z,'edgeColor','none','FaceAlpha',0.2,'FaceColor','b');
                   elseif C(3)==0&&C(1)~=0
                       [Z,Y]=meshgrid(linspace(-10,10,500),linspace(-10,10,500));
                       X=ones(size(Y))*b(i)/C(1);
                      surf(app.UIAxes,X,Y,Z,'edgeColor','none','FaceAlpha',0.2,'FaceColor','y');
                   else
                       msgbox('系数矩阵A输入错误，请按照提示输入正确的矩阵格式','WARN','error');
                end
               end
               %添加图例
               legend(app.UIAxes,'平面一','平面二','平面三','平面四','平面五','平面六','平面七','平面八');
               %求解三元方程组
               Ab=[A,b];
               if rank(A)~=rank(Ab)
                    str='方程组无解';
                   app.TextArea.Value=str;
               elseif rank(A)==3
                   X=A\b;
                   str=['方程组有唯一解，解为：','x=',num2str(X(1)),' ', 'y=', num2str(X(2)),' ','z=',num2str(X(3))];
                   app.TextArea.Value=str;
               elseif rank(A)==1
                   str='方程组有无穷多解，解在一个平面上';
                   app.TextArea.Value=str;
               else 
                   str='方程组有无穷多解，解在一条直线上';
                   app.TextArea.Value=str;
               end

           else
               msgbox('系数矩阵A输入错误，请按照提示输入正确的矩阵格式','WARN','error');
           end

       else
           msgbox('系数矩阵A与常数向量b的维度不符合，请按照提示输入正确的矩阵格式','WARN','error');
       end
       
      
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
      
        end

        % Value changing function: bEditField
        function bEditFieldValueChanging(app, event)
      
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\UI_figure.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '绘图区域')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.YLim = [-5 5];
            app.UIAxes.ZLim = [-5 5];
            app.UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes.ZTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5 6];
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '输入区域';
            app.AEditFieldLabel = uilabel(app.Panel);
            app.AEditFieldLabel.Tooltip = {'请输入nx2或nx3矩阵'};
            app.AEditFieldLabel.Text = '系数矩阵A';
            app.AEditField = uieditfield(app.Panel, 'text');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.AEditField.Tooltip = {'请输入nx2或nx3矩阵'};
            app.bEditFieldLabel = uilabel(app.Panel);
            app.bEditFieldLabel.Tooltip = {'请输入nx1矩阵'};
            app.bEditFieldLabel.Text = '常数向量b';
            app.bEditField = uieditfield(app.Panel, 'text');
            app.bEditField.ValueChangingFcn = createCallbackFcn(app, @bEditFieldValueChanging, true);
            app.bEditField.Tooltip = {'请输入nx1矩阵'};
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '绘制图像';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '输出区域';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '结果';
            app.TextArea = uitextarea(app.Panel_2);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'  二元和三元线性方程组中的每个方程在几何上分别表示平面几何中的直线和立体几何中的平面,那么二元(三元) 线性方程组解的存在性和唯一性就相当于各个方程表示的直线(平面) 的公共交点的存在性和唯一性。'; '  方程组Ax=b中，A表示系数矩阵，x表示变量，b为常数矩阵。'};
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '方程组的几何意义';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = '线性方程组';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '菜单';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '院徽.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Equation_Set

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end