classdef LC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TextArea         matlab.ui.control.TextArea
        Image            matlab.ui.control.Image
        EditField_4      matlab.ui.control.EditField
        Button_2         matlab.ui.control.StateButton
        Panel_2          matlab.ui.container.Panel
        aEditField       matlab.ui.control.EditField
        aEditFieldLabel  matlab.ui.control.Label
        cEditField       matlab.ui.control.EditField
        cEditFieldLabel  matlab.ui.control.Label
        bEditField       matlab.ui.control.EditField
        bEditFieldLabel  matlab.ui.control.Label
        Panel            matlab.ui.container.Panel
        EditField_3      matlab.ui.control.EditField
        Label_3          matlab.ui.control.Label
        EditField_2      matlab.ui.control.EditField
        Label_2          matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        Label            matlab.ui.control.Label
        Button           matlab.ui.control.StateButton
        UIAxes           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)

        function isThreeDimensional = is3DVector(~,vector)
            % 获取向量的尺寸信息
            vectorSize = size(vector);

            % 判断向量是否为三维向量
            if isvector(vector) && vectorSize(1) == 1 && vectorSize(2) == 3
                isThreeDimensional = true;
            else
                isThreeDimensional = false;
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
           cla(app.UIAxes);
            %获取输入向量
            value_1=app.EditField.Value;
            value_2=app.EditField_2.Value;
            value_3=app.EditField_3.Value;
            vec_1=str2num(value_1);
            vec_2=str2num(value_2);
            vec_3=str2num(value_3);

            %判断输入向量格式是否正确
            r1=app.is3DVector(vec_1);
            r2=app.is3DVector(vec_1);
            r3=app.is3DVector(vec_1);
            if (r1==1&&r2==1&&r3==1)
                %向量初始点
                s=[0,0,0];
                %计算叉乘
                C=cross(vec_1,vec_2);
                %选择平面上一点,一般p=s
                p=[0,0,0];

                %点法式方程
                if C(3)~=0
                    [X,Y]=meshgrid(-10:0.1:10,-10:0.1:10);
                    Z=(-C(1).*(X-p(1))-C(2).*(Y-p(2)))./C(3)+p(3);
                    surf(app.UIAxes,X,Y,Z,"FaceColor","none","EdgeColor",'y','FaceAlpha',0.5);
                elseif C(3)==0&&C(2)~=0
                    [X,Z]=meshgrid(-10:0.1:10,-10:0.1:10);
                    Y=-(C(1).*(X-p(1)))./C(2)+p(2);
                    surf(app.UIAxes,X,Y,Z,"FaceColor","none","EdgeColor",'y','FaceAlpha',0.5);
                elseif C(3)==0&&C(1)~=0
                    [Z,Y]=meshgrid(-10:0.1:10,-10:0.1:10);
                    X=-(C(2).*(Y-p(2)))./C(1)+p(1);
                    surf(app.UIAxes,X,Y,Z,"FaceColor","none","EdgeColor",'y','FaceAlpha',0.5);
                end
                %画出向量
                hold (app.UIAxes,'on');
                quiver3(app.UIAxes,s(1),s(2),s(3),vec_1(1),vec_1(2),vec_1(3),'off','r','LineWidth',2,'MaxHeadSize',0.2);
                quiver3(app.UIAxes,s(1),s(2),s(3),vec_2(1),vec_2(2),vec_2(3),'off','g','LineWidth',2,'MaxHeadSize',0.2);
                quiver3(app.UIAxes,s(1),s(2),s(3),vec_3(1),vec_3(2),vec_3(3),'off','b','LineWidth',2,'MaxHeadSize',0.2);
                legend(app.UIAxes,'向量一与向量二张成的空间 ','向量一','向量二','向量三');
                %判断是否线性相关
                A=[vec_1;vec_2;vec_3];
                if rank(A) < numel(vec_1)
                    msgbox('向量线性相关','结果');
                %向量初始点
                f = figure;
                s=[0,0,0];
                %计算出系数并画出矢量三角形
                A=[vec_1;vec_2;vec_3];
                X=null(A');
                vec1=X(1)*vec_1;
                vec2=X(2)*vec_2;
                vec3=X(3)*vec_3;
                quiver3(s(1),s(2),s(3),vec1(1),vec1(2),vec1(3),'off','r','LineWidth',2,'MaxHeadSize',0.2);
                hold on;
                quiver3(vec1(1)+s(1),vec1(2)+s(2),vec1(3)+s(3),vec2(1),vec2(2),vec2(3),'off','g','LineWidth',2,'MaxHeadSize',0.2);
                quiver3(vec1(1)+vec2(1)+s(1),vec1(2)+vec2(2)+s(2),vec1(3)+vec2(3)+s(3),vec3(1),vec3(2),vec3(3),'off','b','LineWidth',2,'MaxHeadSize',0.2);
                xlabel('X轴');
                ylabel('Y轴');
                zlabel('Z轴');
                legend('向量一*系数a','平移后的向量二*系数b','向量三*系数c');
                title('向量生成的矢量三角形');
                a = num2str(round( X(1)* 100) / 100);
                b = num2str(round( X(2)* 100) / 100);
                c = num2str(round( X(3)* 100) / 100);
                app.cEditField.Value = c ;
                app.bEditField.Value = b ;
                app.aEditField.Value = a ;             
                elseif rank(A) == numel(vec_1)
                    msgbox('向量线性无关','结果');
                    d = num2str(0);
                    app.cEditField.Value = d;
                    app.bEditField.Value = d;
                    app.aEditField.Value = d; 
                end

            else
                msgbox('请输入正确的向量格式','TIPS','error');
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
  
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
  
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)

        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\UI_figure.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '绘图区域')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '判断是否相关';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '输入区域';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '输入向量1';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '输入向量2';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '输入向量3';
            app.EditField_3 = uieditfield(app.Panel, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '输出区域';
            app.bEditFieldLabel = uilabel(app.Panel_2);
            app.bEditFieldLabel.Text = '向量二的相关系数b为:';
            app.bEditField = uieditfield(app.Panel_2, 'text');
            app.cEditFieldLabel = uilabel(app.Panel_2);
            app.cEditFieldLabel.Text = '向量三的相关系数c为:';
            app.cEditField = uieditfield(app.Panel_2, 'text');
            app.aEditFieldLabel = uilabel(app.Panel_2);
            app.aEditFieldLabel.Text = '向量一的相关系数a为:';
            app.aEditField = uieditfield(app.Panel_2, 'text');
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '菜单';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Value = '线性相关';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '院徽.jpg');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'向量的线性相关的公式为'; 'a*向量1+b*向量2+c*向量3 = 0'; '当a，b，c均为0时等式才成立则向量线性无关。否则向量线性相关。'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end