classdef Linear_Transformation2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button_2     matlab.ui.control.StateButton
        Image        matlab.ui.control.Image
        EditField_7  matlab.ui.control.EditField
        Panel_2      matlab.ui.container.Panel
        TextArea2    matlab.ui.control.TextArea
        EditField_6  matlab.ui.control.EditField
        EditField_4  matlab.ui.control.EditField
        Label_4      matlab.ui.control.Label
        EditField_5  matlab.ui.control.EditField
        Label_5      matlab.ui.control.Label
        EditField_3  matlab.ui.control.EditField
        Label_3      matlab.ui.control.Label
        TextArea_2   matlab.ui.control.TextArea
        Panel        matlab.ui.container.Panel
        EditField_2  matlab.ui.control.EditField
        Label_2      matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        Label        matlab.ui.control.Label
        Button       matlab.ui.control.StateButton
        UIAxes       matlab.ui.control.UIAxes
    end


    methods (Access = public)
        function isTwoByTwoMatrix = isTwoByTwoMatrix(~,A)
            % 判断A是否是一个矩阵
            if ismatrix(A)
                % 判断A是否是2x2矩阵
                [rows, cols] = size(A);
                if rows == 2 && cols == 2
                    isTwoByTwoMatrix = true;
                else
                    isTwoByTwoMatrix = false;
                end
            else
                isTwoByTwoMatrix = false;
            end
        end
        
        function s=area(~,A,B,C)
            if length(A)==2  %输入三点是二维平面坐标，变成三维
                AB=[B-A 0];
                BC=[C-B 0];
            elseif length(A)==3  %输入三点是三维空间坐标
                AB=B-A;
                BC=C-B;
            end
            Z=cross(AB,BC);  %叉乘
            s=1/2*norm(Z);  %取模
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            cla(app.UIAxes);
            legend(app.UIAxes,'off');

            %获取向量值和矩阵值
            value_1=app.EditField.Value;
            vector=str2num(value_1);

            value_2 = app.EditField_2.Value;
            matrix=str2num(value_2);
            %判断输入矩阵格式是否正确
            r1=app.isTwoByTwoMatrix(vector);
            r2=app.isTwoByTwoMatrix(matrix);
            if (r1==1&&r2==1)
                
                %计算线性变换结果
                value_3=matrix*vector;
                app.EditField_3.Value=mat2str(value_3);

                %画出网格和初始向量
                %网格
                T=diag(1,1);
                t=-2:0.1:2;
                x0=t'*ones(1,41);
                x1=T(1)*x0'+T(3)*x0 ;

                x2=T(1)*x0 +T(3)*x0';

                plot(app.UIAxes,x1,x1','k-',x2,x2','k-');
                hold(app.UIAxes,'on');
                %初始向量
                S=vector;
                q1=quiver(app.UIAxes,0,0,S(1),S(2),'LineWidth',1.1,'color','y');
                hold(app.UIAxes,'on');
                q2=quiver(app.UIAxes,0,0,S(3),S(4),'LineWidth',1.1,'color','r');
                %添加图例
                legend(app.UIAxes,[q1,q2],{'向量一','向量二'});

            elseif (size(vector,1)==2&&r2==1)
                 S=horzcat(vector,[vector(1,1),vector(2,1)]');
                x=S(1,:);
                y=S(2,:);
                plot(app.UIAxes,x,y,'o-','Color','b');
            elseif r2~=1
                msgbox('线性变换矩阵输入错误，请输入正确的2x2矩阵','输入错误','error');
                cla(app.UIAxes);
            else 
                msgbox('初始矩阵输入错误，请输入正确的2xn矩阵','输入错误','error');
                cla(app.UIAxes);
            end
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            cla(app.UIAxes);
            legend(app.UIAxes,'off');

            %获取向量值和矩阵值
            value_1=app.EditField.Value;
            vector=str2num(value_1);
            value_2 = app.EditField_2.Value;
            matrix=str2num(value_2);

            %判断输入矩阵格式是否正确
            r1=app.isTwoByTwoMatrix(vector);
            r2=app.isTwoByTwoMatrix(matrix);
            if (r1==1&&r2==1)
                t=-2:0.1:2;
                x0=t'*ones(1,41);
                S=vector;

                %对网格做线性变换
                for k=0:0.05:1
                    T=[(matrix(1)-1)*k+1,matrix(3)*k;matrix(2)*k,(matrix(4)-1)*k+1];
                    x1=T(1)*x0'+T(3)*x0 ;
                    y1=T(2)*x0'+T(4)*x0 ;
                    x2=T(1)*x0 +T(3)*x0';
                    y2=T(2)*x0 +T(4)*x0';
                    plot(app.UIAxes,x1,y1,'k-',x2,y2,'k-');
                    hold (app.UIAxes,'on');

                    %对向量做线性变换
                    S1=T*S;
                    quiver(app.UIAxes,0,0,S1(1),S1(2),'LineWidth',1.2,'color','y');
                    quiver(app.UIAxes,0,0,S1(3),S1(4),'LineWidth',1.2,'color','r');
                    hold (app.UIAxes,'off');

                    
                    %生成动图，初状态作为循环，初末状态停止1秒，其他停止0.1秒.
                    im=frame2im(getframe(app.UIAxes));
                    [I,map]=rgb2ind(im,20);
                    if (k==0)
                        imwrite(I,map,'linear_trans_gif.gif','gif','LoopCount',inf,'DelayTime',1);
                    elseif (k==1)
                        imwrite(I,map,'linear_trans_gif.gif','gif','WriteMode','append','DelayTime',1);
                    else
                        imwrite(I,map,'linear_trans_gif.gif','gif','WriteMode','append','DelayTime',0.05);
                    end
                end


                %计算线性变换结果
                value_3=matrix*vector;
                app.EditField_3.Value=mat2str(round(value_3,2));

                %生成变换前向量
                hold (app.UIAxes,"on");
                q1=quiver(app.UIAxes,0,0,vector(1),vector(3),'LineStyle','--','LineWidth',1.4,'color','b');
                q2=quiver(app.UIAxes,0,0,vector(2),vector(4),'LineStyle','--','LineWidth',1.4,'color','c');
                x1 =[0,vector(1),vector(1)+vector(2),vector(2)] ;
                y1 =[0,vector(3),vector(3)+vector(4),vector(4)] ;
                q5=fill(app.UIAxes,x1,y1,'r','FaceAlpha',0.1);
                %添加图例

                hold (app.UIAxes,'on');
                q3=quiver(app.UIAxes,0,0,value_3(1),value_3(3),'LineWidth',1.2,'color','y');
                q4=quiver(app.UIAxes,0,0,value_3(2),value_3(4),'LineWidth',1.2,'color','r');
                x2 =[0,value_3(1),value_3(1)+value_3(2),value_3(2)] ;
                y2 =[0,value_3(3),value_3(3)+value_3(4),value_3(4)] ;
                q6 =fill(app.UIAxes,x2,y2,'b','FaceAlpha',0.1);
                legend(app.UIAxes,[q1,q2,q3,q4,q5,q6],{'变化前向量一','变换前向量二','变换后向量一','变换后向量二','变换前生成的面积','变化后生成的面积'});
                % %计算面积
                app.EditField_4.Value =num2str(round(2*app.area(vector(:,1)',vector(:,2)',[0 0]),2));
                app.EditField_5.Value =num2str(round(2*app.area(value_3(:,1)',value_3(:,2)',[0 0]),2));
                app.EditField_6.Value =num2str(round(det(str2num(value_2)),2));

            elseif (size(vector,1)==2&&r2==1)
                for k=0:0.05:1
                    T=[(matrix(1)-1)*k+1,matrix(3)*k;matrix(2)*k,(matrix(4)-1)*k+1];
                    S=horzcat(vector,[vector(1,1),vector(2,1)]');

                    %对图像做线性变换
                    S1=T*S;
                    x=S1(1,:);
                    y=S1(2,:);
                    plot(app.UIAxes,x,y,'o-','Color','b');
                    hold (app.UIAxes,"off");

                    %生成动图，初状态作为循环，初末状态停止1秒，其他停止0.1秒.
                    im=frame2im(getframe(app.UIAxes));
                    [I,map]=rgb2ind(im,20);
                    if (k==0)
                        imwrite(I,map,'linear_trans_gif.gif','gif','LoopCount',inf,'DelayTime',1);
                    elseif (k==1)
                        imwrite(I,map,'linear_trans_gif.gif','gif','WriteMode','append','DelayTime',1);
                    else
                        imwrite(I,map,'linear_trans_gif.gif','gif','WriteMode','append','DelayTime',0.05);
                    end
                end

                %计算线性变换结果
                value_3=matrix*vector;
                app.EditField_3.Value=mat2str(round(value_3,2));

                %生成变换前图像
                hold (app.UIAxes,'on');
                x=S(1,:);
                y=S(2,:);
                p1=plot(app.UIAxes,x,y,'o--','color','r');
                %添加图例
                hold (app.UIAxes,'on');
                x=value_3(1,:);
                y=value_3(2,:);
                p2=plot(app.UIAxes,x,y,'o-',"Color",'b');
                legend(app.UIAxes,[p1,p2],{'变换前图像','变换后图像'});

            elseif r2~=1
                msgbox('线性变换矩阵输入错误，请输入正确的2x2矩阵','输入错误','error');
                cla(app.UIAxes);
            else 
                msgbox('初始矩阵输入错误，请输入正确的2xn矩阵','输入错误','error');
                cla(app.UIAxes);
            end

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            cla(app.UIAxes);
            legend(app.UIAxes,'off');

            %获取向量值和矩阵值
            value_1=app.EditField.Value;
            vector=str2num(value_1);

            value_2 = app.EditField_2.Value;
            %判断矩阵是否为空
            if isempty(value_2)
                matrix=[0,0;0,0];
            else
            matrix=str2num(value_2);
            end
            %判断输入矩阵格式是否正确
            r1=app.isTwoByTwoMatrix(vector);
            r2=app.isTwoByTwoMatrix(matrix);
            if (r1==1&&r2==1)
                
                %计算线性变换结果
                value_3=matrix*vector;
                app.EditField_3.Value=mat2str(round(value_3,2));

                %画出网格和初始向量
                %网格
                T=diag(1,1);
                t=-2:0.1:2;
                x0=t'*ones(1,41);
                x1=T(1)*x0'+T(3)*x0 ;

                x2=T(1)*x0 +T(3)*x0';

                plot(app.UIAxes,x1,x1','k-',x2,x2','k-');
                
                hold(app.UIAxes,'on');
                %初始向量
                S=vector;
                q1=quiver(app.UIAxes,0,0,S(1),S(2),'LineWidth',1.1,'color','y');
                hold(app.UIAxes,'on');
                q2=quiver(app.UIAxes,0,0,S(3),S(4),'LineWidth',1.1,'color','r');
                %添加图例
                legend(app.UIAxes,[q1,q2],{'向量一','向量二'});

            elseif (size(vector,1)==2&&r2==1)
                S=horzcat(vector,[vector(1,1),vector(2,1)]');
                x=S(1,:);
                y=S(2,:);
                plot(app.UIAxes,x,y,'o-','Color','b');
            elseif r2~=1
                msgbox('线性变换矩阵输入错误，请输入正确的2x2矩阵','输入错误','error');
                cla(app.UIAxes);
            else 
                msgbox('初始矩阵输入错误，请输入正确的2xn矩阵','输入错误','error');
                cla(app.UIAxes);
            end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\UI_figure.mlapp;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '绘图区域')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-4 4];
            app.UIAxes.YLim = [-4 4];
            app.UIAxes.XTick = [-4 -3 -2 -1 0 1 2 3 4];
            app.UIAxes.YTick = [-4 -3 -2 -1 0 1 2 3 4];
            app.UIAxes.ZTick = [0 0.5 1];
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '生成图像';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '输入区域';
            app.Label = uilabel(app.Panel);
            app.Label.Tooltip = {'输入2x2矩阵。矩阵的每一列代表一个向量。'};
            app.Label.Text = '输入二阶矩阵';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tooltip = {'输入2x2矩阵。矩阵的每一列代表一个向量。'};
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Tooltip = {'输入2x2非奇异矩阵'};
            app.Label_2.Text = '输入线性变换矩阵';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Tooltip = {'输入2x2非奇异矩阵'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'对于线性变换矩阵A，它的行列式|A|是矩阵函数Ax=y的伸缩比例，其中，x为输入的二阶矩阵，y为输出矩阵。在图中可视化的结果为y矩阵中向量围成面积的大小除以x矩阵中向量围成面积大小的值为|A|的绝对值的大小。特别的，当|A|的值为负时代表空间定向发生了改变，当|A|的值为0时，代表A非满秩，即对空间进行了降维。'};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '输出区域';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Tooltip = {'线性变换矩阵与初始矩阵的乘积。'};
            app.Label_3.Text = '输出矩阵';
            app.EditField_3 = uieditfield(app.Panel_2, 'text');
            app.EditField_3.Tooltip = {'线性变换矩阵与初始矩阵的乘积。'};
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Tooltip = {'输出矩阵形成的向量围成的面积。'};
            app.Label_5.Text = '变换后的面积:';
            app.EditField_5 = uieditfield(app.Panel_2, 'text');
            app.EditField_5.Tooltip = {'输出矩阵形成的向量围成的面积。'};
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Tooltip = {'输出矩阵形成的向量围成的面积。'};
            app.Label_4.Text = '变换前的面积:';
            app.EditField_4 = uieditfield(app.Panel_2, 'text');
            app.EditField_4.Tooltip = {'初始矩阵形成的向量围成的面积'};
            app.EditField_6 = uieditfield(app.Panel_2, 'text');
            app.EditField_6.Tooltip = {'线性变换矩阵的行列式大小'};
            app.TextArea2 = uitextarea(app.Panel_2);
            app.TextArea2.Value = {'线性变换矩阵'; '行列式的值'};
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.EditField_7.Value = '行列式';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '院徽.jpg');
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '菜单';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Linear_Transformation2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end