classdef Rotational_Transformation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Button_2     matlab.ui.control.Button
        EditField_5  matlab.ui.control.EditField
        Image        matlab.ui.control.Image
        Panel_2      matlab.ui.container.Panel
        EditField_4  matlab.ui.control.EditField
        Label_6      matlab.ui.control.Label
        EditField_3  matlab.ui.control.EditField
        Label_5      matlab.ui.control.Label
        bSlider      matlab.ui.control.Slider
        Label_4      matlab.ui.control.Label
        Button       matlab.ui.control.Button
        aSlider      matlab.ui.control.Slider
        Label        matlab.ui.control.Label
        Panel        matlab.ui.container.Panel
        EditField_2  matlab.ui.control.EditField
        Label_3      matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        Label_2      matlab.ui.control.Label
        Image2       matlab.ui.control.Image
        UIAxes       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function isTwoByTwoMatrix = isTwoByTwoMatrix(~,A)
            % 判断A是否是一个矩阵
            if ismatrix(A)
                % 判断A是否是2x2矩阵
                [rows, cols] = size(A);
                if rows == 2 && cols == 2
                    isTwoByTwoMatrix = true;
                else
                    isTwoByTwoMatrix = false;
                end
            else
                isTwoByTwoMatrix = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            cla(app.UIAxes);
            %获取向量值
            value_1=app.EditField.Value;
            vector=str2num(value_1);
            value_2 = app.EditField_2.Value;
            %判断矩阵是否为空
            if isempty(value_2)
                matrix=[0,0;0,0];
            else
                matrix=str2num(value_2);
            end
            %判断输入矩阵格式是否正确
            r2=app.isTwoByTwoMatrix(matrix);
            if (size(vector,1)==2&&r2==1)

                %画出图像
                S=horzcat(vector,[vector(1,1),vector(2,1)]');
                x=S(1,:);
                y=S(2,:);
                plot(app.UIAxes,x,y,'o-','Color','b');
                fill(app.UIAxes,x,y,'b');
            elseif r2~=1
                msgbox('线性变换矩阵输入错误，请输入正确的2x2矩阵','输入错误','error');
                cla(app.UIAxes);
            else
                msgbox('初始矩阵输入错误，请输入正确的2xn矩阵','输入错误','error');
                cla(app.UIAxes);
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            cla(app.UIAxes);
            %获取向量值
            value_1=app.EditField.Value;
            vector=str2num(value_1);
            value_2 = app.EditField_2.Value;
            %判断矩阵是否为空
            if isempty(value_2)
                matrix=[1 0;0 1];
            else
            matrix=str2num(value_2);
           
            end
            %判断输入矩阵格式是否正确
            r2=app.isTwoByTwoMatrix(matrix);
            if (size(vector,1)==2&&r2==1)

                %画出图像
                S=horzcat(vector,[vector(1,1),vector(2,1)]');
                x=S(1,:);
                y=S(2,:);
                plot(app.UIAxes,x,y,'o-','Color','k');
                fill(app.UIAxes,x,y,'b');

                %起作用矩阵
                %图像放缩矩阵
                value_4=app.bSlider.Value;
                matrix_SF=[round(value_4,2) 0;0 round(value_4,2)];
                S_SF=matrix_SF*matrix;

                %图像旋转矩阵
                value_3=app.aSlider.Value;
                matrix_R=[cosd(value_3) -sind(value_3);sind(value_3) cosd(value_3)];
                S1=matrix_R*S_SF;
                %输出起作用矩阵
                app.EditField_3.Value=mat2str(S1);

                %输出结果矩阵
                SV= vector;
                S2=matrix_R*matrix_SF*matrix*SV;
                app.EditField_4.Value=mat2str(S2);
            elseif r2~=1
                msgbox('线性变换矩阵输入错误，请输入正确的2x2矩阵','输入错误','error');
                cla(app.UIAxes);
            else
                msgbox('初始矩阵输入错误，请输入正确的2xn矩阵','输入错误','error');
                cla(app.UIAxes);
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
          cla(app.UIAxes);
          %获取向量值和矩阵值
          value_1=app.EditField.Value;
          vector=str2num(value_1);
          value_2 = app.EditField_2.Value;
          %判断矩阵是否为空
          if isempty(value_2)
              matrix=[1 0;0 1];
          else
              matrix=str2num(value_2);
          end
          %获取放缩矩阵和旋转矩阵
          value_4=app.bSlider.Value;
          matrix_SF=[round(value_4,2) 0;0 round(value_4,2)];
          value_3=app.aSlider.Value;
          matrix_R=[cosd(value_3) -sind(value_3);sind(value_3) cosd(value_3)];
       
          %判断输入矩阵格式是否正确
          r2=app.isTwoByTwoMatrix(matrix);
          if (size(vector,1)==2&&r2==1)
              S=vector;
              S1=matrix_R*matrix_SF*matrix*S;
              x=S1(1,:);
              y=S1(2,:);
              plot(app.UIAxes,x,y,'o-',"Color",'k');
              fill(app.UIAxes,x,y,'b');

              %输出起作用矩阵
              S1=matrix_R*matrix_SF*matrix;
              app.EditField_3.Value=mat2str(round(S1,2));
              %输出结果矩阵
              S2=matrix_R*matrix_SF*matrix*vector;
              app.EditField_4.Value=mat2str(round(S2,2));

          elseif r2~=1
              msgbox('线性变换矩阵输入错误，请输入正确的2x2矩阵','输入错误','error');
              cla(app.UIAxes);
          else
              msgbox('初始矩阵输入错误，请输入正确的2xn矩阵','输入错误','error');
              cla(app.UIAxes);
            end

        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            cla(app.UIAxes);
            %获取向量值
            value_1=app.EditField.Value;
            vector=str2num(value_1);
            value_2 = app.EditField_2.Value;
            %判断矩阵是否为空
            if isempty(value_2)
                matrix=[1 0;0 1];
               
            else
            matrix=str2num(value_2);
            
            end
            %判断输入矩阵格式是否正确
            r2=app.isTwoByTwoMatrix(matrix);
            if (size(vector,1)==2&&r2==1)
                %图像放缩
                value_4=app.bSlider.Value;
                matrix_SF=[round(value_4,2) 0;0 round(value_4,2)];
                S=vector;
                S_SF=matrix_SF*matrix*S;

                %图像旋转
                value_3=app.aSlider.Value;
                matrix_R=[cosd(value_3) -sind(value_3);sind(value_3) cosd(value_3)];
                S1=matrix_R*S_SF;
                %画出图像
                x=S1(1,:);
                y=S1(2,:);
                plot(app.UIAxes,x,y,'o-',"Color",'k');
                fill(app.UIAxes,x,y,'b');

                %输出起作用矩阵
                S1=matrix_R*matrix_SF*matrix;
                app.EditField_3.Value=mat2str(round(S1,2));
                %输出结果矩阵
                S2=matrix_R*matrix_SF*matrix*vector;
                app.EditField_4.Value=mat2str(round(S2,2));
            elseif r2~=1
                msgbox('线性变换矩阵输入错误，请输入正确的2x2矩阵','输入错误','error');
                cla(app.UIAxes);
            else
                msgbox('初始矩阵输入错误，请输入正确的2xn矩阵','输入错误','error');
                cla(app.UIAxes);
            end
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
          
        end

        % Value changing function: bSlider
        function bSliderValueChanging(app, event)
            cla(app.UIAxes);
            %获取向量值
            value_1=app.EditField.Value;
            vector=str2num(value_1);
            value_2 = app.EditField_2.Value;
             %判断矩阵是否为空
            if isempty(value_2)
                matrix=[1 0;0 1];
              
            else
            matrix=str2num(value_2);
           
            end
            %判断输入矩阵格式是否正确
            r2=app.isTwoByTwoMatrix(matrix);
            if (size(vector,1)==2&&r2==1)
                %图像放缩
                value_4=app.bSlider.Value;
                matrix_SF=[round(value_4,2) 0;0 round(value_4,2)];
                S=vector;
                S_SF=matrix_SF*matrix*S;

                %图像旋转
                value_3=app.aSlider.Value;
                matrix_R=[cosd(value_3) -sind(value_3);sind(value_3) cosd(value_3)];
                S1=matrix_R*S_SF;
                
                %画出图像
                x=S1(1,:);
                y=S1(2,:);
                plot(app.UIAxes,x,y,'o-',"Color",'k');
                fill(app.UIAxes,x,y,'b');

                %输出起作用矩阵
                S1=matrix_R*matrix_SF*matrix;
                app.EditField_3.Value=mat2str(round(S1,2));
                %输出结果矩阵
                S2=matrix_R*matrix_SF*matrix*vector;
                app.EditField_4.Value=mat2str(round(S2,2));
            elseif r2~=1
                msgbox('线性变换矩阵输入错误，请输入正确的2x2矩阵','输入错误','error');
                cla(app.UIAxes);
            else
                msgbox('初始矩阵输入错误，请输入正确的2xn矩阵','输入错误','error');
                cla(app.UIAxes);
            end
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
           
        end

        % Value changing function: EditField_3
        function EditField_3ValueChanging(app, event)
            
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\UI_figure.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '绘图区域')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.YLim = [-5 5];
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '屏幕截图 2024-05-07 223138.png');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '输入区域';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Tooltip = {'输入2xn矩阵。矩阵的每一列代表坐标轴中一点。'};
            app.Label_2.Text = '输入初始矩阵';
            app.EditField = uieditfield(app.Panel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tooltip = {'输入2xn矩阵。矩阵的每一列代表坐标轴中一点'};
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Tooltip = {'输入2x2非奇异矩阵'};
            app.Label_3.Text = '线性变换矩阵';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Tooltip = {'输入2x2非奇异矩阵'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '旋转角度a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.Limits = [0 360];
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.aSlider.Tooltip = {'矩阵的形式为'; '[cosa   -sina]'; '[sina     cosa]'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '生成图像';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '放缩倍数b';
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.Limits = [0.2 5];
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.bSlider.ValueChangingFcn = createCallbackFcn(app, @bSliderValueChanging, true);
            app.bSlider.Tooltip = {'矩阵的形式为'; '[b   0]'; '[0   b]'};
            app.bSlider.Value = 1;
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '输出区域';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Tooltip = {'起作用矩阵x初始矩阵'};
            app.Label_5.Text = '起作用矩阵';
            app.EditField_3 = uieditfield(app.Panel_2, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.ValueChangingFcn = createCallbackFcn(app, @EditField_3ValueChanging, true);
            app.EditField_3.Tooltip = {'放缩矩阵x旋转矩阵x线性变换矩阵'};
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Tooltip = {'起作用矩阵x初始矩阵'};
            app.Label_6.Text = '输出矩阵';
            app.EditField_4 = uieditfield(app.Panel_2, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Tooltip = {'起作用矩阵x初始矩阵'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '院徽.jpg');
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.Value = '线性变换';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '菜单';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rotational_Transformation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end