classdef UI_figure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Image        matlab.ui.control.Image
        Button_13    matlab.ui.control.Button
        GIFButton    matlab.ui.control.StateButton
        Button_11    matlab.ui.control.StateButton
        Button_10    matlab.ui.control.StateButton
        EditField    matlab.ui.control.EditField
        Button_9     matlab.ui.control.StateButton
        Button_8     matlab.ui.control.StateButton
        Button_7     matlab.ui.control.StateButton
        Button_6     matlab.ui.control.StateButton
        Button_5     matlab.ui.control.StateButton
        Button_4     matlab.ui.control.StateButton
        EditField_2  matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\Cross_Multiplication.mlapp;
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            delete(app.UIFigure);
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\LC.mlapp;
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\Linear_Transformation2.mlapp;
        end

        % Value changed function: Button_10
        function Button_10ValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\Rotational_Transformation.mlapp;
            
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\Equation_Set.mlapp;
            
        end

        % Value changed function: Button_7
        function Button_7ValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\xiangliangbiaoshi.mlapp;
            
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\EigenVector.mlapp;
        end

        % Value changed function: Button_11
        function Button_11ValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\erci.mlapp;           
        end

        % Value changed function: GIFButton
        function GIFButtonValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\xianxing1.mlapp;     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Button_4 = uibutton(app.UIFigure, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '特征向量';
            app.Button_5 = uibutton(app.UIFigure, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Text = '线性相关';
            app.Button_6 = uibutton(app.UIFigure, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Text = '方程组求解';
            app.Button_7 = uibutton(app.UIFigure, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Button_7ValueChanged, true);
            app.Button_7.Text = '向量运算';
            app.Button_8 = uibutton(app.UIFigure, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Text = '向量叉乘';
            app.Button_9 = uibutton(app.UIFigure, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Text = '行列式';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '线性代数可视化';
            app.Button_10 = uibutton(app.UIFigure, 'state');
            app.Button_10.ValueChangedFcn = createCallbackFcn(app, @Button_10ValueChanged, true);
            app.Button_10.Text = '线性变换';
            app.Button_11 = uibutton(app.UIFigure, 'state');
            app.Button_11.ValueChangedFcn = createCallbackFcn(app, @Button_11ValueChanged, true);
            app.Button_11.Text = '二次型';
            app.GIFButton = uibutton(app.UIFigure, 'state');
            app.GIFButton.ValueChangedFcn = createCallbackFcn(app, @GIFButtonValueChanged, true);
            app.GIFButton.Text = 'GIF变换';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '退出';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '院徽.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_figure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end