classdef erci < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Image        matlab.ui.control.Image
        Button_4     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        TextArea     matlab.ui.control.TextArea
        Panel_2      matlab.ui.container.Panel
        TextArea_5   matlab.ui.control.TextArea
        Label_5      matlab.ui.control.Label
        TextArea_4   matlab.ui.control.TextArea
        Label_4      matlab.ui.control.Label
        TextArea_3   matlab.ui.control.TextArea
        Label_3      matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        TextArea_2   matlab.ui.control.TextArea
        Panel        matlab.ui.container.Panel
        EditField_2  matlab.ui.control.EditField
        Label        matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            userInput = value;
            pattern = '^-?\d+(\.\d+)?\s-?\d+(\.\d+)?\s-?\d+(\.\d+)?$';
            if regexp(userInput, pattern)
                if isempty(value)
                    vec_1=[0,0,0];
                else
                    vec_1=str2num(value);
                end
                %输出二次型矩阵
                A = [vec_1(1) 0.5*vec_1(3);0.5*vec_1(3) vec_1(2)];
                str=['[ ',num2str(A(1,1)),'     ', num2str(A(1,2)), ' ]\n' ...
                    '[ ' ,num2str(A(2,1)), '     ',num2str(A(2,2)),' ]'];
                app.TextArea_2.Value=sprintf(str);
            else
                errordlg('系数输入不符合数字 数字 数字中间用空格隔开(例子:2 0 4)的形式，请重新输入！', '输入错误', 'modal');
                app.EditField_2.Value = '';
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            value = app.EditField_2.Value;
            userInput = value;
           pattern = '^-?\d+(\.\d+)?\s-?\d+(\.\d+)?\s-?\d+(\.\d+)?$';
            if regexp(userInput, pattern)
            if isempty(value)
                vec_1=[0,0,0];
            else
                vec_1=str2num(value);
            end
            A = [vec_1(1) 0.5*vec_1(3);0.5*vec_1(3) vec_1(2)];
            % 绘制二次型的图形
            [X, Y] = meshgrid(-10:0.5:10, -10:0.5:10);
            Z = A(1,1)*X.^2 + (A(1,2) + A(2,1))*X.*Y + A(2,2)*Y.^2;
            surf(app.UIAxes, X, Y, Z);
            xlabel(app.UIAxes, 'x1');
            ylabel(app.UIAxes, 'x2');
            zlabel(app.UIAxes, 'f(x)');
            else
                errordlg('系数输入不符合数字 数字 数字中间用空格隔开(例子:2 0 4)的形式，请重新输入！', '输入错误', 'modal');
                app.EditField_2.Value = '';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = app.EditField_2.Value;
            userInput = value;
            pattern = '^-?\d+(\.\d+)?\s-?\d+(\.\d+)?\s-?\d+(\.\d+)?$';
            if regexp(userInput, pattern)
            if isempty(value)
                vec_1=[0,0,0];
            else
                vec_1=str2num(value);
            end
            A = [vec_1(1) 0.5*vec_1(3);0.5*vec_1(3) vec_1(2)];
            % 对矩阵 A 进行正交对角化
              [P, D] = eig(A);
              %输出正交矩阵
            str=['[ ',num2str(round(P(1,1),2)),'     ', num2str(round(P(1,2),2)), ' ]\n' ...
                 '[ ' ,num2str(round(P(2,1),2)), '     ',num2str(round(P(2,2),2)),' ]'];
            app.TextArea_3.Value=sprintf(str);
            %输出标准化函数
            str =['g(y)= (',num2str(round(D(1,1),2)),')*y1^2 + (', num2str(round(D(2,2),2)), ')*y2^2'] ;
                 
            app.TextArea_4.Value=sprintf(str);
            % 判断曲面类型
            eigenvalues = diag(D);
            if all(eigenvalues > 0)
                app.TextArea_5.Value = '抛物面';
            elseif all(eigenvalues < 0)
                app.TextArea_5.Value = '抛物面';
            elseif any(eigenvalues > 0) && any(eigenvalues < 0)
                app.TextArea_5.Value = '马鞍面';
            else
                app.TextArea_5.Value = '抛物线面';
            end
            %做出新的图像
            figure;
            % 绘制二次型的图形
            [X, Y] = meshgrid(-10:0.5:10, -10:0.5:10);
            Z = D(1,1)*X.^2 + (D(1,2) + D(2,1))*X.*Y + D(2,2)*Y.^2;
            surf(X, Y, Z);
            xlabel('y1');
            ylabel('y2');
            zlabel('g(y)');
            title('正交对角化后的曲面');
            else
                errordlg('系数输入不符合数字 数字 数字中间用空格隔开(例子:2 0 4)的形式，请重新输入！', '输入错误', 'modal');
                app.EditField_2.Value = '';
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.EditField_2.Value = '';
            app.TextArea_2.Value='';
            app.TextArea_3.Value='';
            app.TextArea_4.Value='';
            app.TextArea_5.Value='';
            cla(app.UIAxes);
            close;

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\UI_figure.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '绘图区域')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-20 20];
            app.UIAxes.YLim = [-20 20];
            app.UIAxes.ZLim = [-100 100];
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '二次型';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '输入区域';
            app.Label = uilabel(app.Panel);
            app.Label.Tooltip = {'请输入三维向量'};
            app.Label.Text = '输入系数';
            app.EditField_2 = uieditfield(app.Panel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Tooltip = {'请输入非零非共线三维向量'};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '输出区域';
            app.TextArea_2 = uitextarea(app.Panel_2);
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '输出二次型矩阵';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '输出正交矩阵';
            app.TextArea_3 = uitextarea(app.Panel_2);
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '输出标准化方程';
            app.TextArea_4 = uitextarea(app.Panel_2);
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '判断曲面类型';
            app.TextArea_5 = uitextarea(app.Panel_2);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'二次型也称为二次齐次多项式，我们这里讨论的为由两个未知数组成的方程，我们输入该二次函数的系数，分别对应a,b,c.其中f(x)=ax1^2+bx2^2+cx1x2'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '正交对角化';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '菜单';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '生成图像';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '还原';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '院徽.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = erci

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end