classdef xiangliangbiaoshi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        TextArea    matlab.ui.control.TextArea
        Button_2    matlab.ui.control.StateButton
        Image       matlab.ui.control.Image
        EditField   matlab.ui.control.EditField
        Panel_2     matlab.ui.container.Panel
        a3          matlab.ui.control.EditField
        Label_4     matlab.ui.control.Label
        Panel       matlab.ui.container.Panel
        a2          matlab.ui.control.EditField
        Label_2     matlab.ui.control.Label
        a1          matlab.ui.control.EditField
        Label       matlab.ui.control.Label
        Button      matlab.ui.control.StateButton
        DropDown    matlab.ui.control.DropDown
        Label_3     matlab.ui.control.Label
        ResultAxes  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            if isempty(app.a1.Value)
                b1 = 1;
            else
                b1 = 0;
            end

            if isempty(app.a2.Value)
                b2 = 1;
            else
                b2 = 0;
            end
            % 在绘图区域中显示结果
            if  b1== 0 && b2 == 1
                userInput = app.a1.Value;
                pattern = '^-?\d+(\.\d+)?\s-?\d+(\.\d+)?\s-?\d+(\.\d+)?$';
                if regexp(userInput, pattern)
                    cla(app.ResultAxes,"reset");
                    vec1 = app.a1.Value;
                    v1 = str2num(vec1);
                    if     v1(1) == 0
                        quiver(app.ResultAxes,0,0,v1(2),v1(3),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                        xlabel(app.ResultAxes, 'Y轴');
                        ylabel(app.ResultAxes, 'Z轴');
                        legend(app.ResultAxes,{'向量a'});
                    elseif v1(2) == 0
                        quiver(app.ResultAxes,0,0,v1(1),v1(3),"off",'b','LineWidth',2','MaxHeadSize',0.2);
                        xlabel(app.ResultAxes, 'X轴');
                        ylabel(app.ResultAxes, 'Z轴');
                        legend(app.ResultAxes,{'向量a'});
                    elseif  v1(3) == 0
                        quiver(app.ResultAxes,0,0,v1(1),v1(2),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                        xlabel(app.ResultAxes, 'X轴');
                        ylabel(app.ResultAxes, 'Y轴');
                        legend(app.ResultAxes,{'向量a'});
                    else
                        quiver3(app.ResultAxes,0,0,0,v1(1),v1(2),v1(3),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                        xlabel(app.ResultAxes, 'X轴');
                        ylabel(app.ResultAxes, 'Y轴');
                        zlabel(app.ResultAxes, 'Z轴');
                        legend(app.ResultAxes,{'向量a'});
                    end
                else
                    errordlg('向量一输入不符合数字 数字 数字中间用空格隔开(例子:2 0 4)的形式，请重新输入！', '输入错误', 'modal');
                    app.a1.Value = '';
                end

            elseif b1== 1 && b2 == 0
                userInput2 = app.a2.Value;
                pattern = '^-?\d+(\.\d+)?\s-?\d+(\.\d+)?\s-?\d+(\.\d+)?$';
                if regexp(userInput2, pattern)
                    cla(app.ResultAxes,"reset");
                    vec2 = app.a2.Value;
                    v2 = str2num(vec2);
                    if      v2(1) == 0
                        quiver(app.ResultAxes,0,0,v2(2),v2(3),"off",'g','LineWidth',2,'MaxHeadSize',0.2);
                        xlabel(app.ResultAxes, 'Y轴');
                        ylabel(app.ResultAxes, 'Z轴');
                        legend(app.ResultAxes,{'向量b'});
                    elseif  v2(2) == 0
                        quiver(app.ResultAxes,0,0,v2(1),v2(3),"off",'g','LineWidth',2,'MaxHeadSize',0.2);
                        xlabel(app.ResultAxes, 'X轴');
                        ylabel(app.ResultAxes, 'Z轴');
                        legend(app.ResultAxes,{'向量b'});
                    elseif    v2(3) == 0
                        quiver(app.ResultAxes,0,0,v2(1),v2(2),"off",'g','LineWidth',2,'MaxHeadSize',0.2);
                        xlabel(app.ResultAxes, 'X轴');
                        ylabel(app.ResultAxes, 'Y轴');
                        legend(app.ResultAxes,{'向量b'});
                    else
                        quiver3(app.ResultAxes,0,0,0,v2(1),v2(2),v2(3),"off",'g','LineWidth',2,'MaxHeadSize',0.2);
                        xlabel(app.ResultAxes, 'X轴');
                        ylabel(app.ResultAxes, 'Y轴');
                        zlabel(app.ResultAxes, 'Z轴');
                        legend(app.ResultAxes,{'向量b'});
                    end
                else
                    errordlg('向量b输入不符合数字 数字 数字中间用空格隔开(例子:2 0 4)的形式，请重新输入！', '输入错误', 'modal');
                    app.a2.Value = '';
                end

            elseif b1== 1 && b2 == 1
                errordlg('请输入向量按照数字 数字 数字中间用空格隔开(例子:2 0 4)的形式！', '未输入向量', 'modal');
            elseif b1 == 0 && b2 == 0
                userInput2 = app.a2.Value;
                userInput = app.a1.Value;
                % 使用正则表达式进行匹配
                pattern = '^-?\d+(\.\d+)?\s-?\d+(\.\d+)?\s-?\d+(\.\d+)?$';
                if  ~isempty(regexp(userInput2, pattern)) == 1 && ~isempty(regexp(userInput, pattern))==1
                    cla(app.ResultAxes,"reset");
                    % 获取用户输入的向量和操作
                    vec1 = app.a1.Value;
                    vec2 = app.a2.Value;
                    operation = app.DropDown.Value;

                    % 将字符串转换为向量
                    v1 = str2num(vec1);
                    v2 = str2num(vec2);

                    % 执行所选的操作
                    switch operation
                        case '加法'
                            result = v1 + v2;
                        case '减法'
                            result = v1 - v2;
                        case '内积'
                            result = v1 .* v2;
                    end
                     app.a3.Value = mat2str(result);
                    if v1(1)==0 && v2(1)==0
                        switch operation
                            case '加法'
                                quiver(app.ResultAxes,0,0,v1(2),v1(3),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                                hold(app.ResultAxes,"on");
                                quiver(app.ResultAxes,v1(2),v1(3),v2(2),v2(3),"off",'g','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,result(2),result(3),"off",'r','LineWidth', 2,'MaxHeadSize',0.2);
                                xlabel(app.ResultAxes, 'Y轴');
                                ylabel(app.ResultAxes, 'Z轴');
                                legend(app.ResultAxes,{'向量a','向量b','结果向量'});
                            case '减法'
                                quiver(app.ResultAxes,0,0,v1(2),v1(3),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                                hold(app.ResultAxes,"on");
                                quiver(app.ResultAxes,v1(2),v1(3),-v2(2),-v2(3),"off",'g','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,result(2),result(3),"off",'r','LineWidth', 2,'MaxHeadSize',0.2);
                                xlabel(app.ResultAxes, 'Y轴');
                                ylabel(app.ResultAxes, 'Z轴');
                                legend(app.ResultAxes,{'向量a','向量b','结果向量'});
                            case '内积'
                                quiver(app.ResultAxes,0,0,v1(2),v1(3),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                                hold(app.ResultAxes,"on");
                                quiver(app.ResultAxes,0,0,v2(2),v2(3),"off",'g','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,result(2),result(3),"off",'r','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,result(2),0,"off",'r--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,0,result(3),"off",'r--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,result(2),0,0,result(3),"off",'k--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,result(3),result(2),0,"off",'k--','LineWidth', 1,'MaxHeadSize',0.2);
                                xlabel(app.ResultAxes, 'Y轴');
                                ylabel(app.ResultAxes, 'Z轴');
                                legend(app.ResultAxes,{'向量a','向量b','结果向量','ay*by','az*bz'});
                             end
                    elseif v1(2)==0 && v2(2)==0
                        switch operation
                            case '加法'
                                quiver(app.ResultAxes,0,0,v1(1),v1(3),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                                hold(app.ResultAxes,"on");
                                quiver(app.ResultAxes,v1(1),v1(3),v2(1),v2(3),"off",'g','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,result(1),result(3),"off",'r','LineWidth', 2,'MaxHeadSize',0.2);
                                xlabel(app.ResultAxes, 'X轴');
                                ylabel(app.ResultAxes, 'Z轴');
                                legend(app.ResultAxes,{'向量a','向量b','结果向量'});
                            case '减法'
                                quiver(app.ResultAxes,0,0,v1(1),v1(3),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                                hold(app.ResultAxes,"on");
                                quiver(app.ResultAxes,v1(1),v1(3),-v2(1),-v2(3),"off",'g','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,result(1),result(3),"off",'r','LineWidth', 2,'MaxHeadSize',0.2);
                                xlabel(app.ResultAxes, 'X轴');
                                ylabel(app.ResultAxes, 'Z轴');
                                legend(app.ResultAxes,{'向量a','向量b','结果向量'});
                            case '内积'
                                quiver(app.ResultAxes,0,0,v1(1),v1(3),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                                hold(app.ResultAxes,"on");
                                quiver(app.ResultAxes,0,0,v2(1),v2(3),"off",'g','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,result(1),result(3),"off",'r','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,result(1),0,"off",'r--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,0,result(3),"off",'r--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,result(1),0,0,result(3),"off",'k--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,result(3),result(1),0,"off",'k--','LineWidth', 1,'MaxHeadSize',0.2);
                                xlabel(app.ResultAxes, 'X轴');
                                ylabel(app.ResultAxes, 'Z轴');
                                legend(app.ResultAxes,{'向量a','向量b','结果向量','ax*bx','az*bz'});                                                      
                        end
                    elseif v1(3)==0 && v2(3)==0
                        switch operation
                            case '加法'
                                quiver(app.ResultAxes,0,0,v1(1),v1(2),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                                hold(app.ResultAxes,"on");
                                quiver(app.ResultAxes,v1(1),v1(2),v2(1),v2(2),"off",'g','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,result(1),result(2),"off",'r','LineWidth', 2,'MaxHeadSize',0.2);
                                xlabel(app.ResultAxes, 'X轴');
                                ylabel(app.ResultAxes, 'Y轴');
                                legend(app.ResultAxes,{'向量a','向量b','结果向量'});
                            case '减法'
                                quiver(app.ResultAxes,0,0,v1(1),v1(2),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                                hold(app.ResultAxes,"on");
                                quiver(app.ResultAxes,v1(1),v1(2),-v2(1),-v2(2),"off",'g','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,result(1),result(2),"off",'r','LineWidth', 2,'MaxHeadSize',0.2);
                                xlabel(app.ResultAxes, 'X轴');
                                ylabel(app.ResultAxes, 'Y轴');
                                legend(app.ResultAxes,{'向量a','向量b','结果向量'});
                            case '内积'
                                quiver(app.ResultAxes,0,0,v1(1),v1(2),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                                hold(app.ResultAxes,"on");
                                quiver(app.ResultAxes,0,0,v2(1),v2(2),"off",'g','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,result(1),result(2),"off",'r','LineWidth', 2,'MaxHeadSize',0.2)
                                quiver(app.ResultAxes,0,0,result(1),0,"off",'r--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,0,0,result(2),"off",'r--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,result(1),0,0,result(2),"off",'k--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver(app.ResultAxes,0,result(2),result(1),0,"off",'k--','LineWidth', 1,'MaxHeadSize',0.2);
                                xlabel(app.ResultAxes, 'X轴');
                                ylabel(app.ResultAxes, 'Y轴');
                                legend(app.ResultAxes,{'向量a','向量b','结果向量','ax*bx','ay*by'});                        
                        end
                    else
                        switch operation
                            case '加法'
                                quiver3(app.ResultAxes,0,0,0,v1(1),v1(2),v1(3),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                                hold(app.ResultAxes,"on");
                                quiver3(app.ResultAxes,v1(1),v1(2),v1(3),v2(1),v2(2),v2(3),"off",'g','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver3(app.ResultAxes,0,0,0,result(1),result(2),result(3),"off",'r','LineWidth', 2,'MaxHeadSize',0.2);
                                xlabel(app.ResultAxes, 'X轴');
                                ylabel(app.ResultAxes, 'Y轴');
                                zlabel(app.ResultAxes, 'Z轴');
                                legend(app.ResultAxes,{'向量a','向量b','结果向量'});
                            case '减法'
                                quiver3(app.ResultAxes,0,0,0,v1(1),v1(2),v1(3),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                                hold(app.ResultAxes,"on");
                                quiver3(app.ResultAxes,v1(1),v1(2),v1(3),-v2(1),-v2(2),-v2(3),"off",'g','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver3(app.ResultAxes,0,0,0,result(1),result(2),result(3),"off",'r','LineWidth', 2,'MaxHeadSize',0.2);
                                xlabel(app.ResultAxes, 'X轴');
                                ylabel(app.ResultAxes, 'Y轴');
                                zlabel(app.ResultAxes, 'Z轴');
                                legend(app.ResultAxes,{'向量a','向量b','结果向量'});
                            case '内积'
                                quiver3(app.ResultAxes,0,0,0,v1(1),v1(2),v1(3),"off",'b','LineWidth',2,'MaxHeadSize',0.2);
                                hold(app.ResultAxes,"on");
                                quiver3(app.ResultAxes,0,0,0,v2(1),v2(2),v2(3),"off",'g','LineWidth', 2,'MaxHeadSize',0.2);
                                quiver3(app.ResultAxes,0,0,0,result(1),result(2),result(3),"off",'r','LineWidth',2,'MaxHeadSize',0.2);
                                quiver3(app.ResultAxes,0,0,0,result(1),0,0,"off",'r--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver3(app.ResultAxes,0,0,0,0,result(2),0,"off",'r--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver3(app.ResultAxes,0,0,0,0,0,result(3),"off",'r--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver3(app.ResultAxes,result(1),0,0,0,result(2),result(3),"off",'k--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver3(app.ResultAxes,0,result(2),0,result(1),0,result(3),"off",'k--','LineWidth', 1,'MaxHeadSize',0.2);
                                quiver3(app.ResultAxes,0,0,result(3),result(1),result(2),0,"off",'k--','LineWidth', 1,'MaxHeadSize',0.2);                             
                                xlabel(app.ResultAxes, 'X轴');
                                ylabel(app.ResultAxes, 'Y轴');
                                zlabel(app.ResultAxes, 'Z轴');
                                legend(app.ResultAxes,{'向量a','向量b','结果向量','ax*bx','ay*by','az*bz'});                            
                        end

                    end
                elseif ~isempty(regexp(userInput2, pattern)) == 0 && ~isempty(regexp(userInput, pattern))==1
                    errordlg('向量b输入不符合数字 数字 数字中间用空格隔开(例子:2 0 4)的形式，请重新输入！', '输入错误', 'modal');
                    app.a2.Value = '';
                elseif ~isempty(regexp(userInput2, pattern)) == 1 && ~isempty(regexp(userInput, pattern))==0
                    errordlg('向量a输入不符合数字 数字 数字中间用空格隔开(例子:2 0 4)的形式，请重新输入！', '输入错误', 'modal');
                    app.a1.Value = '';
                else
                    errordlg('向量a与向量b都输入不符合数字 数字 数字中间用空格隔开(例子:2 0 4)的形式，请重新输入！', '输入错误', 'modal');
                    app.a1.Value = '';
                    app.a2.Value = '';
                end
            end
        end

        % Value changed function: a2
        function a2ValueChanged(app, event)
            userInput = app.a1.Value;
            if isempty(app.a1.Value)
                b1 = 0;
            else
                b1 = 1;
            end
            userInput2 = app.a2.Value;
            if isempty(app.a2.Value)
                b2 = 0;
            else
                b2 = 1;
            end

            % 使用正则表达式进行匹配
            pattern = '^\d+\s\d+\s\d+$';
            if regexp(userInput2, pattern)
                if regexp(userInput, pattern)
                vec1 = app.a1.Value;
                vec2 = app.a2.Value;
                operation = app.DropDown.Value;

                % 将字符串转换为向量
                v1 = str2num(vec1);
                v2 = str2num(vec2);

                % 执行所选的操作
                switch operation
                    case '加法'
                        result = v1 + v2;
                    case '减法'
                        result = v1 - v2;
                    case '内积'
                        result = v1 .* v2;                  
                end
                elseif b1==0
                else
                    app.a1.Value = '';
            errordlg('向量一输入不符合数字 数字 数字中间用空格隔开(例子:2 0 4)的形式，请重新输入！', '输入错误', 'modal');
                end              
            elseif b2 ==1
            else
                % 清除输入的数据
                app.a2.Value = '';
                errordlg('向量二输入不符合数字 数字 数字中间用空格隔开(例子:2 0 4)的形式，请重新输入！', '输入错误', 'modal');
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if isempty(app.a1.Value)
                b1 = 0;
            else
                b1 = 1;
            end
            if isempty(app.a2.Value)
                b2 = 0;
            else
                b2 = 1;
            end
            if b1 == 0
                v1=[0 0 0];
            else
                vec1 = app.a1.Value;
                v1 = str2num(vec1);
            end
            if b2==0
                v2=[0 0 0];
            else
                vec2 = app.a2.Value;
                v2 = str2num(vec2);
            end
            operation = app.DropDown.Value;
            % 执行所选的操作
            switch operation
                case '加法'
                    result = v1 + v2;
                case '减法'
                    result = v1 - v2;
                case '内积'
                    result = v1 .* v2;               
            end
            app.a3.Value = mat2str(result);
            
        end

        % Value changed function: a1
        function a1ValueChanged(app, event)
            % 获取用户输入
        userInput = app.a1.Value;
        if isempty(app.a1.Value)
                b1 = 0;
            else
                b1 = 1;
        end

        % 使用正则表达式进行匹配
        pattern = '^\d+\s\d+\s\d+$';
        if regexp(userInput, pattern)
        elseif b1 == 1
        else
            % 清除输入的数据
            app.a1.Value = '';
            errordlg('向量一输入不符合数字 数字 数字中间用空格隔开(例子:2 0 4)的形式，请重新输入！', '输入错误', 'modal');
        end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\UI_figure.mlapp;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResultAxes = uiaxes(app.UIFigure);
            title(app.ResultAxes, '绘图区域')
            xlabel(app.ResultAxes, 'X轴')
            ylabel(app.ResultAxes, 'Y轴')
            zlabel(app.ResultAxes, 'Z轴')
            app.ResultAxes.XLim = [-20 20];
            app.ResultAxes.YLim = [-20 20];
            app.ResultAxes.ZLim = [-20 20];
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '请选择你要进行的操作：';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'加法', '减法', '内积'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '加法';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '生成图像';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '输入区域';
            app.Label = uilabel(app.Panel);
            app.Label.Tooltip = {'蓝色'};
            app.Label.Text = '请输入向量a：';
            app.a1 = uieditfield(app.Panel, 'text');
            app.a1.ValueChangedFcn = createCallbackFcn(app, @a1ValueChanged, true);
            app.a1.Tooltip = {'蓝色'};
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '请输入向量b：';
            app.a2 = uieditfield(app.Panel, 'text');
            app.a2.ValueChangedFcn = createCallbackFcn(app, @a2ValueChanged, true);
            app.a2.Tooltip = {'绿色'};
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '输出区域';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '结果向量：';
            app.a3 = uieditfield(app.Panel_2, 'text');
            app.a3.Tooltip = {'红色'};
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '向量运算';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '院徽.jpg');
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Text = '菜单';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'向量运算包括加法，减法与内积和叉乘，我们先考虑前三种情况。'; '向量加减法为对应坐标相加减在图中表示为矢量三角形。'; '[a b c]+[d e f] = [a+d b+e c+f]'; '减法类似'; '向量内积为对应坐标相乘再相加'; '[a b c].*[d e f] = [ad be cf]'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xiangliangbiaoshi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end