classdef xianxing1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Panel_2      matlab.ui.container.Panel
        TextArea     matlab.ui.control.TextArea
        Label_5      matlab.ui.control.Label
        Panel        matlab.ui.container.Panel
        EditField    matlab.ui.control.EditField
        Label_3      matlab.ui.control.Label
        Spinner_2    matlab.ui.control.Spinner
        Label_2      matlab.ui.control.Label
        Spinner      matlab.ui.control.Spinner
        Label        matlab.ui.control.Label
        Spinner_3    matlab.ui.control.Spinner
        Label_4      matlab.ui.control.Label
        Button_3     matlab.ui.control.Button
        TextArea_2   matlab.ui.control.TextArea
        EditField_2  matlab.ui.control.EditField
        Image        matlab.ui.control.Image
        GIFCheckBox  matlab.ui.control.CheckBox
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.StateButton
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: Spinner_3
        function Spinner_3ValueChanging(app, event)

        end

        % Value changed function: Spinner_3
        function Spinner_3ValueChanged(app, event)
            cla(app.UIAxes);
            %获取点阵阶数
            n=app.Spinner_3.Value;
            global X Y 
            %画出点阵
            if n~=0
                x=-(n-1)/2:1:(n-1)/2;
                y=-(n-1)/2:1:(n-1)/2;
                [X,Y]=meshgrid(x,y);
                plot(app.UIAxes,X,Y,'.','MarkerSize',20);
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.Spinner_2.Value = 1;
            app.EditField.Value ='';
            %获取旋转矩阵
            a=app.Spinner.Value;
            matrix_R=[cosd(a) -sind(a);sind(a) cosd(a)];
            matrix=matrix_R;
            str=['[ cos(',num2str(a),')      -sin(', num2str(a), ') ]\n' ...
                 '[ sin(' ,num2str(a), ')     cos(',num2str(a),') ]'];
            app.TextArea.Value=sprintf(str);
            n=app.Spinner_3.Value;
            %对点阵做线性变换
            global X Y
            % 初始化一个单元数组来存储每个生成的图像帧
            frames = cell(1, 51); % 51 是因为 k 从 0 到 1，步长为 0.02，共有51 个值

            % 循环遍历 k
            for idx = 1:51
                k = (idx-1)* 0.02; % 计算当前 k 的值

                T = [(matrix(1)-1)*k+1, matrix(3)*k; matrix(2)*k, (matrix(4)-1)*k+1];
                %线性变换
                S=zeros(2,n^2);
                S(1,1:end)=reshape(X,1,[]);
                S(2,1:end)=reshape(Y,1,[]);
                S_R=T*S;
                SX1=reshape(S_R(1,1:end),n,n);
                SY1=reshape(S_R(2,1:end),n,n);
                plot(app.UIAxes,SX1,SY1,'.','MarkerSize',20);
                hold (app.UIAxes,'off');
                % 其他操作

                % 获取当前图像帧
                im = frame2im(getframe(app.UIAxes));
                frames{idx} = im; % 存储当前图像帧
            end
            %继承数据
            X=SX1;
            Y=SY1;
            % 循环结束后，将存储的图像帧生成 GIF
            for idx = 1:numel(frames)
                [I, map] = rgb2ind(frames{idx}, 20);
                if idx == 1
                    global valueGIF
                    if valueGIF
                    % 如果是第一帧，创建新的 GIF 文件
                    imwrite(I, map, 'linear_trans_gif.gif', 'gif', 'LoopCount', inf, 'DelayTime', 1);
                    else
                    imwrite(I, map, 'linear_trans_gif.gif', 'gif', 'WriteMode', 'append', 'DelayTime', 1);    
                    end
                else
                    % 否则，将当前帧追加到现有的 GIF 文件中
                    imwrite(I, map, 'linear_trans_gif.gif', 'gif', 'WriteMode', 'append', 'DelayTime', 0.04);
                end
            end
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            app.Spinner.Value = 0;
            app.EditField.Value ='';
            %获取放缩矩阵
            s=app.Spinner_2.Value;
            matrix_S=[s 0;0 s];
            mat = matrix_S;
            matrix=matrix_S;
            str=['[ ',num2str(mat(1,1)),'     ', num2str(mat(1,2)), ' ]\n' ...
                 '[ ' ,num2str(mat(2,1)), '     ',num2str(mat(2,2)),' ]'];
            app.TextArea.Value=sprintf(str);

            n=app.Spinner_3.Value;
            global X Y
            %对点阵做线性变换
            % 初始化一个单元数组来存储每个生成的图像帧
            frames = cell(1, 51); % 101 是因为 k 从 0 到 1，步长为 0.02，共有 51 个值

            % 循环遍历 k
            for idx = 1:51
                k = (idx-1)* 0.02; % 计算当前 k 的值

                T = [(matrix(1)-1)*k+1, matrix(3)*k; matrix(2)*k, (matrix(4)-1)*k+1];
                %线性变换
                S=zeros(2,n^2);
                S(1,1:end)=reshape(X,1,[]);
                S(2,1:end)=reshape(Y,1,[]);
                S_R=T*S;
                SX1=reshape(S_R(1,1:end),n,n);
                SY1=reshape(S_R(2,1:end),n,n);
                plot(app.UIAxes,SX1,SY1,'.','MarkerSize',20);
                hold (app.UIAxes,'off');
                % 其他操作

                % 获取当前图像帧
                im = frame2im(getframe(app.UIAxes));
                frames{idx} = im; % 存储当前图像帧
            end
            %继承数据
            X=SX1;
            Y=SY1;
            % 循环结束后，将存储的图像帧生成 GIF
            for idx = 1:numel(frames)
                [I, map] = rgb2ind(frames{idx}, 20);
                if idx == 1
                    global valueGIF
                    if valueGIF
                    % 如果是第一帧，创建新的 GIF 文件
                    imwrite(I, map, 'linear_trans_gif.gif', 'gif', 'LoopCount', inf, 'DelayTime', 1);
                    else
                    imwrite(I, map, 'linear_trans_gif.gif', 'gif', 'WriteMode', 'append', 'DelayTime', 1);    
                    end
                else
                    % 否则，将当前帧追加到现有的 GIF 文件中
                    imwrite(I, map, 'linear_trans_gif.gif', 'gif', 'WriteMode', 'append', 'DelayTime', 0.04);
                end
            end
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            %获取线性变换矩阵
            value=app.EditField.Value;
            app.Spinner.Value = 0;
            app.Spinner_2.Value = 1;
            mat=str2num(value);
            matrix=mat;
            str=['[ ',num2str(mat(1,1)),'     ', num2str(mat(1,2)),' ]\n' ...
                 '[ ',num2str(mat(2,1)),'   ',num2str(mat(2,2)),' ]'];
            app.TextArea.Value=sprintf(str);

            n=app.Spinner_3.Value;
            global X Y
            %对点阵做线性变换
            % 初始化一个单元数组来存储每个生成的图像帧
            frames = cell(1, 51); % 101 是因为 k 从 0 到 1，步长为 0.02，共有 51 个值

            % 循环遍历 k
            for idx = 1:51
                k = (idx-1)* 0.02; % 计算当前 k 的值

                T = [(matrix(1)-1)*k+1, matrix(3)*k; matrix(2)*k, (matrix(4)-1)*k+1];
                %线性变换
                S=zeros(2,n^2);
                S(1,1:end)=reshape(X,1,[]);
                S(2,1:end)=reshape(Y,1,[]);
                S_R=T*S;
                SX1=reshape(S_R(1,1:end),n,n);
                SY1=reshape(S_R(2,1:end),n,n);
                plot(app.UIAxes,SX1,SY1,'.','MarkerSize',20);
                hold (app.UIAxes,'off');
                % 其他操作

                % 获取当前图像帧
                im = frame2im(getframe(app.UIAxes));
                frames{idx} = im; % 存储当前图像帧
            end
            %继承数据
            X=SX1;
            Y=SY1;
            % 循环结束后，将存储的图像帧生成 GIF
            for idx = 1:numel(frames)
                [I, map] = rgb2ind(frames{idx}, 20);
                if idx == 1
                    global valueGIF
                    if valueGIF
                    % 如果是第一帧，创建新的 GIF 文件
                    imwrite(I, map, 'linear_trans_gif.gif', 'gif', 'LoopCount', inf, 'DelayTime', 1);
                    else
                    imwrite(I, map, 'linear_trans_gif.gif', 'gif', 'WriteMode', 'append', 'DelayTime', 1);    
                    end
                else
                    % 否则，将当前帧追加到现有的 GIF 文件中
                    imwrite(I, map, 'linear_trans_gif.gif', 'gif', 'WriteMode', 'append', 'DelayTime', 0.04);
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            cla(app.UIAxes);
                app.Spinner_2.Value = 1;
                app.Spinner.Value = 0;
                app.EditField.Value ='';
                str = '';
                app.TextArea.Value=str;
                
            %获取点阵阶数
            n=app.Spinner_3.Value;

            global X Y
            %画出点阵
            if n~=0
                x=-(n-1)/2:1:(n-1)/2;
                y=-(n-1)/2:1:(n-1)/2;
                [X,Y]=meshgrid(x,y);
                plot(app.UIAxes,X,Y,'.','MarkerSize',20);
            end    
        end

        % Value changed function: GIFCheckBox
        function GIFCheckBoxValueChanged(app, event)
            global valueGIF
            valueGIF = app.GIFCheckBox.Value;
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            delete(app.UIFigure);
            run C:\Users\王\Desktop\练习\可视化项目\代码大纲\UI_figure.mlapp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '绘图区域')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-15 15];
            app.UIAxes.YLim = [-15 15];
            app.UIAxes.ZLim = [-100 100];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '生成图像';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '还原';
            app.GIFCheckBox = uicheckbox(app.UIFigure);
            app.GIFCheckBox.ValueChangedFcn = createCallbackFcn(app, @GIFCheckBoxValueChanged, true);
            app.GIFCheckBox.Text = '生成新的GIF';
            app.GIFCheckBox.Value = true;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '院徽.jpg');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'GIF变换';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'此项目对生成的点列进行线性变换并输出gif于文件夹中，以便操作者更直观的看出线性变换的具体过程。'};
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '菜单';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '输入区域';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '点阵阶数';
            app.Spinner_3 = uispinner(app.Panel);
            app.Spinner_3.ValueChangingFcn = createCallbackFcn(app, @Spinner_3ValueChanging, true);
            app.Spinner_3.Limits = [0 Inf];
            app.Spinner_3.ValueChangedFcn = createCallbackFcn(app, @Spinner_3ValueChanged, true);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '旋转角度';
            app.Spinner = uispinner(app.Panel);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '放缩倍数';
            app.Spinner_2 = uispinner(app.Panel);
            app.Spinner_2.Step = 0.5;
            app.Spinner_2.Limits = [0 Inf];
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Value = 1;
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '线性变换矩阵';
            app.EditField = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '输出区域';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '输出作用矩阵形式:';
            app.TextArea = uitextarea(app.Panel_2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xianxing1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end